classdef dispersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        WavelengthrangenmSliderSetUp    matlab.ui.control.RangeSlider
        WavelengthrangenmSliderLabel    matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        asfunctionofSwitch_3            matlab.ui.control.ToggleSwitch
        asfunctionofLabel_2             matlab.ui.control.Label
        savegraphButton                 matlab.ui.control.Button
        resetgraphButton                matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        noterefindexcontain             matlab.ui.control.Label
        noterefindex                    matlab.ui.control.Label
        materialnameDropDown            matlab.ui.control.DropDown
        materialnameDropDownLabel       matlab.ui.control.Label
        RefractiveindexEditField        matlab.ui.control.EditField
        RefractiveindexEditFieldLabel   matlab.ui.control.Label
        lengthEditField                 matlab.ui.control.EditField
        lengthEditFieldLabel            matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        subgroupDropDown                matlab.ui.control.DropDown
        subgroupDropDownLabel           matlab.ui.control.Label
        groupDropDown                   matlab.ui.control.DropDown
        groupDropDownLabel              matlab.ui.control.Label
        notelengthnumber                matlab.ui.control.Label
        notelength                      matlab.ui.control.Label
        notematerial                    matlab.ui.control.Label
        materialSwitch                  matlab.ui.control.Switch
        AddOpticalElementsLabel         matlab.ui.control.Label
        addelementButton                matlab.ui.control.StateButton
        CalculateDispersionButton       matlab.ui.control.Button
        rownumberEditField              matlab.ui.control.EditField
        rownumberEditField_Label        matlab.ui.control.Label
        DeletematerialButton            matlab.ui.control.Button
        UITablematerials                matlab.ui.control.Table
        UIAxesRefIndex                  matlab.ui.control.UIAxes
        DispersionFunctionTab           matlab.ui.container.Tab
        WavelengthrangenmSliderDispersion  matlab.ui.control.RangeSlider
        WavelengthrangenmSliderLabel_2  matlab.ui.control.Label
        DispersionParameterorderSpinner  matlab.ui.control.Spinner
        DispersionParameterorderSpinnerLabel  matlab.ui.control.Label
        PODTextArea                     matlab.ui.control.TextArea
        resetgraphButton_2              matlab.ui.control.Button
        savegraphButton_2               matlab.ui.control.Button
        asfunctionofSwitch_4            matlab.ui.control.ToggleSwitch
        asfunctionofLabel               matlab.ui.control.Label
        DispersionControlButton         matlab.ui.control.Button
        UITablelistmaterials            matlab.ui.control.Table
        orderSpinner                    matlab.ui.control.Spinner
        orderSpinnerLabel               matlab.ui.control.Label
        wavelengthinfreespacenmSlider   matlab.ui.control.Slider
        wavelengthinfreespaceLabel      matlab.ui.control.Label
        UIAxesDispersion                matlab.ui.control.UIAxes
        DispersionControlComponentTab   matlab.ui.container.Tab
        WavelengthrangenmSliderControlComponent  matlab.ui.control.RangeSlider
        WavelengthrangenmSliderLabel_3  matlab.ui.control.Label
        materialnameprismDropDown       matlab.ui.control.DropDown
        materialnameprismDropDown_Label  matlab.ui.control.Label
        subgroupprismDropDown           matlab.ui.control.DropDown
        subgroupprismDropDown_Label     matlab.ui.control.Label
        groupprismDropDown              matlab.ui.control.DropDown
        groupprismDropDown_Label        matlab.ui.control.Label
        notematerialprism               matlab.ui.control.Label
        noterefindexcontainprism        matlab.ui.control.Label
        RefractiveindexprismEditField   matlab.ui.control.EditField
        RefractiveindexprismEditField_Label  matlab.ui.control.Label
        nameprismEditField              matlab.ui.control.EditField
        nameprismEditField_Label        matlab.ui.control.Label
        noterefindexprism               matlab.ui.control.Label
        DispersionControlComponentLabel  matlab.ui.control.Label
        asfunctionofSwitch_5            matlab.ui.control.ToggleSwitch
        asfunctionofSwitch_5Label       matlab.ui.control.Label
        savegraphButton_3               matlab.ui.control.Button
        resetgraphButton_3              matlab.ui.control.Button
        CalculateDispersionControlButton  matlab.ui.control.Button
        materialPrismSwitch             matlab.ui.control.Switch
        addprismmaterialButton          matlab.ui.control.StateButton
        UIAxesPrismRI                   matlab.ui.control.UIAxes
        DispersionControlTab            matlab.ui.container.Tab
        resetgraphButton_5              matlab.ui.control.Button
        savegraphButton_5               matlab.ui.control.Button
        WavelengthrangenmSliderControl  matlab.ui.control.RangeSlider
        WavelengthrangenmSliderLabel_4  matlab.ui.control.Label
        orderSpinner_3                  matlab.ui.control.Spinner
        orderSpinner_3Label             matlab.ui.control.Label
        DispersionParameterorderSpinnerTotal  matlab.ui.control.Spinner
        DispersionParameterorderSpinner_2Label  matlab.ui.control.Label
        PODTextAreaTotal                matlab.ui.control.TextArea
        asfunctionofSwitch_7            matlab.ui.control.ToggleSwitch
        asfunctionofSwitch_7Label       matlab.ui.control.Label
        DropDownControlComponent        matlab.ui.control.DropDown
        Panel_graiting                  matlab.ui.container.Panel
        notelengthnumbergrating_2       matlab.ui.control.Label
        GratingdensityCheckBox          matlab.ui.control.CheckBox
        angleofincidenceCheckBox        matlab.ui.control.CheckBox
        DistanceLCheckBox               matlab.ui.control.CheckBox
        GDD0forLabel                    matlab.ui.control.Label
        notelengthnumbergrating         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        angleofincidenceEditField       matlab.ui.control.EditField
        CalculateButtonGrating          matlab.ui.control.StateButton
        DistanceLEditField              matlab.ui.control.EditField
        GratingPeriodEditField          matlab.ui.control.EditField
        Panel_mirror                    matlab.ui.container.Panel
        UnitsDropDownCol2               matlab.ui.control.DropDown
        UnitsDropDown_2Label            matlab.ui.control.Label
        UnitsDropDownCol1               matlab.ui.control.DropDown
        UnitsDropDownLabel              matlab.ui.control.Label
        Col2DropDown                    matlab.ui.control.DropDown
        Col2DropDownLabel               matlab.ui.control.Label
        Col1DropDown                    matlab.ui.control.DropDown
        Col1DropDownLabel               matlab.ui.control.Label
        uploadExelFileButton            matlab.ui.control.Button
        Panel_window                    matlab.ui.container.Panel
        LEditField                      matlab.ui.control.EditField
        LEditFieldLabel                 matlab.ui.control.Label
        notelengthnumberwindow          matlab.ui.control.Label
        CalculateButtonWindow           matlab.ui.control.Button
        Unit2_2                         matlab.ui.control.TextArea
        LTextArea                       matlab.ui.control.TextArea
        LTextAreaLabel                  matlab.ui.control.Label
        GDD0atLabel_2                   matlab.ui.control.Label
        Panel_prism                     matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        RPrismseparationLabel           matlab.ui.control.Label
        HaccumulativepathlengthinglassLabel  matlab.ui.control.Label
        notelengthnumberprism           matlab.ui.control.Label
        Unit                            matlab.ui.control.TextArea
        Unit3                           matlab.ui.control.TextArea
        Unit2                           matlab.ui.control.TextArea
        CalculateButtonPrism            matlab.ui.control.Button
        HEditField                      matlab.ui.control.EditField
        HEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.EditField
        DistanceLEditField_2Label_2     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        HTextArea                       matlab.ui.control.TextArea
        HTextAreaLabel                  matlab.ui.control.Label
        RTextArea                       matlab.ui.control.TextArea
        RTextAreaLabel                  matlab.ui.control.Label
        RHTextArea                      matlab.ui.control.TextArea
        RHTextAreaLabel                 matlab.ui.control.Label
        GDD0atLabel                     matlab.ui.control.Label
        DispersionControlByButtonGroup  matlab.ui.container.ButtonGroup
        GratingpairButton               matlab.ui.control.ToggleButton
        DispersiveMirrorButton          matlab.ui.control.ToggleButton
        WindowButton                    matlab.ui.control.ToggleButton
        PrismPairButton                 matlab.ui.control.ToggleButton
        wavelengthinfreespacenmSliderControl  matlab.ui.control.Slider
        wavelengthinfreespacenmSlider_3Label  matlab.ui.control.Label
        UIAxesControlComponent          matlab.ui.control.UIAxes
        UIAxesDispersionControlTotal    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Wavelength
        Prism_Ref_Index
        Prism_name
        Order
        group
        book
        page
        ref_index
        fdispersion
        fphic
        fref_index
        fdis_prism
        fRsecend
        fRthird
        fHthird
        fRfourth
        fHfourth
        fLsecend
        phi2_prism
        phi2_window
        phi2_grating
        omega0
        SelectedCompenentslog
        SelectedMaterialDelRow
        limitw
        limitlamb
        isprism
        name_ref_index
        length_ref_index
        str_ref_index
        fref_index_prism
        R
        H
        L
        gratingL
        gratingAngle
        gratingPeriod
        ftotat_dispersion
        axesIsw
        refgraphtype
        POD_order
        selectedControl
        Col1File
        Col2File
        FileGdd
        FileTod
        isFile
        isFileUploaded
        Col1Unit
        Col2Unit
        Col1Data
        Col2Data
        phi2_control
        controlgraphtype
        controltext
        fphiTotalMaterials
        fphi0TotalMaterials
        flagValueChosen
        prismpairOrder
        checkboxGrating
        phi2_total_grating
    end
    
    methods (Access = private)
        
        function dispersionFunction(app)
            if isempty(app.SelectedCompenentslog)
                app.SelectedCompenentslog = logical(ones(height(app.UITablematerials.Data),1));
            end
            c = 299792458;
            app.UITablelistmaterials.Data = table(app.SelectedCompenentslog,app.UITablematerials.Data(:,1));
            app.Order = app.orderSpinner.Value;
            app.omega0 = 2*pi*c/app.Wavelength;
            syms w omega0
            syms fphi(w,omega0) phi(w) 
            [fphi(w,omega0), phi(w)] = DispersionFunction(app.UITablematerials.Data(app.SelectedCompenentslog,:), app.Order);
            app.PODTextArea.Value = strcat(string(double(subs(diff(phi, app.POD_order),w,app.omega0)*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']');
            app.fdispersion = fphi(w,omega0);
            app.fphic = phi(w);
            fphi(w) = subs(fphi, omega0, app.omega0);
            txt = "\lambda_0 =  ";
            txt = strcat(txt, int2str(app.Wavelength*10^9));
            txt = strcat(txt, "nm, order = ");
            txt = strcat(txt, int2str(app.Order));
            if app.axesIsw   
                xlabel(app.UIAxesDispersion, '\omega[PHz]')
                ylabel(app.UIAxesDispersion, '\phi(\omega)')
                app.UIAxesDispersion.XLim = app.limitw;
                fplot(app.UIAxesDispersion, abs(fphi(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            else 
                app.UIAxesDispersion.XLim = app.limitlamb;
                xlabel(app.UIAxesDispersion, '\lambda[µm]')
                ylabel(app.UIAxesDispersion, '\phi(\lambda)')
                syms lamb
                syms fphi_l(lamb)
                fphi_l(lamb) = subs(fphi(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesDispersion, abs(fphi_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesDispersion);
        end
        function dispersionControl(app)
            if app.selectedControl == "Dispersive Mirror"
                if app.isFileUploaded
                    ControlComponentGraph(app)
                end
            elseif app.selectedControl == "Prism Pair"
                    dispersionControlPrismPair(app)
            elseif app.selectedControl == "Window"
                dispersionControlWindow(app)
            else 
                dispersionControlGratingPair(app)
            end
            cla(app.UIAxesDispersionControlTotal)
            TotalGraph(app)
        end
        function TotalGraph(app)
            c = 299792458;
            syms w omega0
            syms fphi(w,omega0) phi(w) 
            fphi(w,omega0) = app.fphiTotalMaterials;
            phi(w) = app.fphi0TotalMaterials;
            if app.isFile && app.isFileUploaded
                if app.Col1File == "omega" 
                    app.limitlamb = sort(2*pi*c./[app.Col1Data(1) app.Col1Data(end-2)] *10^6);
                    app.limitw = sort([app.Col1Data(1) app.Col1Data(end-2)] *10^-15);
                    changeRange(app, app.limitlamb*10^3)
                    index_omega0 = interp1(app.Col1Data(1:end-2), 1:numel(app.Col1Data(1:end-2)), app.omega0, 'nearest', 'extrap');
                    app.omega0 = app.Col1Data(index_omega0);
                    app.Wavelength = 2*pi*c/app.omega0;
                    changeWavelength(app, app.Wavelength*10^9)
                    omega = app.Col1Data(1:end-2);
                else
                    app.limitlamb = sort([app.Col1Data(1) app.Col1Data(end-2)] *10^6);
                    app.limitw = sort(2*pi*c./[app.Col1Data(end-2) app.Col1Data(1)] *10^-15);
                    changeRange(app, app.limitlamb*10^3)
                    index_omega0 = interp1(2*pi*c./app.Col1Data(1:end-2), 1:numel(app.Col1Data(1:end-2)), app.omega0, 'nearest', 'extrap');
                    app.Wavelength = app.Col1Data(index_omega0);
                    app.omega0 = 2*pi*c/app.Wavelength;
                    changeWavelength(app, app.Wavelength*10^9)
                    omega = 2*pi*c./app.Col1Data(1:end-2);
                end
                gdd0 = app.FileGdd(index_omega0);
                tod0 = app.FileTod(index_omega0);
                if app.POD_order >= 3
                    if app.POD_order > 3
                        changePODorder(app, 3)
                    end
                    app.POD_order = 3;
                    phi_n = tod0 + subs(diff(phi, app.POD_order),w,app.omega0);
                elseif app.POD_order <= 2
                    if app.POD_order < 2
                        changePODorder(app, 2)
                    end
                    app.POD_order = 2;
                    phi_n = gdd0 + subs(diff(phi, app.POD_order),w,app.omega0);
                end
                app.PODTextAreaTotal.Value = strcat(string(double(phi_n*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']');
                fphi(w) = subs(fphi(w,omega0), omega0, app.omega0);
                Tphi = fphi(omega);
                if app.Order > 1
                    Tphi = Tphi + gdd0/2 *(omega-app.omega0).^2;
                end
                if app.Order > 2
                    Tphi = Tphi + tod0/6 *(omega-app.omega0).^3;
                end
                txt = "\lambda_0 =  ";
                txt = strcat(txt, int2str(app.Wavelength*10^9));
                txt = strcat(txt, "nm, order = ");
                txt = strcat(txt, int2str(app.Order));
                xlim(app.UIAxesDispersionControlTotal,"tight");
                if app.axesIsw
                    xlabel(app.UIAxesDispersionControlTotal, '\omega[PHz]')
                    ylabel(app.UIAxesDispersionControlTotal, '\phi(\omega)')
                    plot(app.UIAxesDispersionControlTotal, omega*10^-15, Tphi,'LineWidth', 1.5,'DisplayName', txt);
                else 
                    xlabel(app.UIAxesDispersionControlTotal, '\lambda[µm]')
                    ylabel(app.UIAxesDispersionControlTotal, '\phi(\lambda)')
                    plot(app.UIAxesDispersionControlTotal, 10^6 *2*pi*c./omega, Tphi,'LineWidth', 1.5,'DisplayName', txt);   
                end    
            else
                syms GDD(omega0) 
                if app.isFile && ~app.isFileUploaded
                    GDD(omega0) = 0;
                else
                    GDD(omega0) = app.phi2_control;
                end
                phi_n = double(subs(diff(phi, app.POD_order),w,app.omega0)) + double(subs(diff(GDD, app.POD_order-2),omega0,app.omega0));
                app.PODTextAreaTotal.Value = strcat(string(double(phi_n*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']');
                for n = 2 : app.Order
                    fphi(w,omega0) = fphi(w,omega0) + (w-omega0)^n /factorial(n) * diff(GDD(omega0), n-2,omega0);
                end
                fphi(w) = subs(fphi(w,omega0), omega0, app.omega0);
                txt = "\lambda_0 =  ";
                txt = strcat(txt, int2str(app.Wavelength*10^9));
                txt = strcat(txt, "nm, order = ");
                txt = strcat(txt, int2str(app.Order));
                if app.axesIsw   
                    xlabel(app.UIAxesDispersionControlTotal, '\omega[PHz]')
                    ylabel(app.UIAxesDispersionControlTotal, '\phi(\omega)')
                    app.UIAxesDispersionControlTotal.XLim = app.limitw;
                    fplot(app.UIAxesDispersionControlTotal, abs(fphi(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
                else 
                    app.UIAxesDispersionControlTotal.XLim = app.limitlamb;
                    xlabel(app.UIAxesDispersionControlTotal, '\lambda[µm]')
                    ylabel(app.UIAxesDispersionControlTotal, '\phi(\lambda)')
                    syms lamb
                    syms fphi_l(lamb)
                    fphi_l(lamb) = subs(fphi(w), w, 2*pi*c/lamb);
                    fplot(app.UIAxesDispersionControlTotal, abs(fphi_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
                end
            end
            legend(app.UIAxesDispersionControlTotal);
        end

        function ControlComponentGraph(app)
            c = 299792458;
            if app.isFile
                if app.axesIsw
                    xlabel(app.UIAxesControlComponent, '\omega[PHz]')
                    xlim(app.UIAxesControlComponent,"tight");
                    if app.controlgraphtype == "GDD"
                        ylabel(app.UIAxesControlComponent, 'GDD(\omega)[fs^2]');
                        if app.Col1File == "omega"
                            plot(app.UIAxesControlComponent, app.Col1Data(1:end-1)*10^-15, app.FileGdd*10^30);
                        else
                            plot(app.UIAxesControlComponent, 2*pi*c./app.Col1Data(1:end-1)*10^-15, app.FileGdd*10^30);
                        end
                    else 
                        ylabel(app.UIAxesControlComponent, 'TOD(\omega)[fs^3]');
                        if app.Col1File == "omega"
                            plot(app.UIAxesControlComponent, app.Col1Data(1:end-2)*10^-15, app.FileTod*10^45);
                        else
                            plot(app.UIAxesControlComponent, 2*pi*c./app.Col1Data(1:end-2)*10^-15, app.FileTod*10^45);
                        end
                    end
                else 
                    xlabel(app.UIAxesControlComponent, '\lambda[µm]')
                    xlim(app.UIAxesControlComponent,"tight");
                    if app.controlgraphtype == "GDD"
                        ylabel(app.UIAxesControlComponent, 'GDD(\omega)[fs^2]');
                        if app.Col1File == "omega"
                            plot(app.UIAxesControlComponent, 2*pi*c./app.Col1Data(1:end-1)*10^6, app.FileGdd*10^30);
                        else
                            plot(app.UIAxesControlComponent, app.Col1Data(1:end-1)*10^6, app.FileGdd*10^30);
                        end
                    else 
                        ylabel(app.UIAxesControlComponent, 'TOD(\omega)[fs^3]');
                        if app.Col1File == "omega"
                            plot(app.UIAxesControlComponent, 2*pi*c./app.Col1Data(1:end-2)*10^6, app.FileTod*10^45);
                        else
                            plot(app.UIAxesControlComponent, app.Col1Data(1:end-2)*10^6, app.FileTod*10^45);
                        end
                    end          
                end
            else
                syms omega0
                syms GDD(omega0) TOD(omega0)
                GDD(omega0) = app.phi2_control;
                if app.axesIsw
                    xlabel(app.UIAxesControlComponent, '\omega[PHz]')
                    app.UIAxesControlComponent.XLim = app.limitw;
                    if app.controlgraphtype == "GDD"
                        ylabel(app.UIAxesControlComponent, 'GDD(\omega)[fs^2]')
                        fplot(app.UIAxesControlComponent, (10^30)*real(GDD(omega0*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', app.controltext);
                    else
                        TOD(omega0) = diff(GDD,1,omega0);
                        ylabel(app.UIAxesControlComponent, 'TOD(\omega)[fs^3]')
                        fplot(app.UIAxesControlComponent, (10^45)*real(TOD(omega0*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', app.controltext);
                    end
                else 
                    app.UIAxesControlComponent.XLim = app.limitlamb;
                    xlabel(app.UIAxesControlComponent, '\lambda[µm]')
                    syms lamb
                    syms GDD_l(lamb) TOD_l(lamb)
                    if app.controlgraphtype == "GDD"
                        GDD_l(lamb) = subs(GDD(omega0), omega0, 2*pi*c/lamb);
                        ylabel(app.UIAxesControlComponent, 'GDD(\lambda)[fs^2]')
                        fplot(app.UIAxesControlComponent, (10^30)*real(GDD_l(lamb*10^-6)), app.limitlamb,'LineWidth', 1.5,'DisplayName', app.controltext);
                    else
                        TOD(omega0) = diff(GDD,1,omega0);
                        TOD_l(lamb) = subs(TOD(omega0), omega0, 2*pi*c/lamb);
                        ylabel(app.UIAxesControlComponent, 'TOD(\lambda)[fs^3]')
                        fplot(app.UIAxesControlComponent, (10^45)*real(TOD_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', app.controltext);
                    end
                end
                legend(app.UIAxesControlComponent);
            end
        end
        function dispersionControlOmega0Chamged(app)
            if app.selectedControl == "Dispersive Mirror"
            elseif app.selectedControl == "Grating pair"
            elseif app.selectedControl == "Prism Pair"
                syms omega0 H R R2(H,omega0)
                R2(omega0,H) = app.fRsecend;
                R2(H) = vpa(subs(R2(omega0,H),omega0,app.omega0));
                app.RHTextArea.Value = string(vpa(10^3 *R2(H),5));
                RR = 0;
                HH = 0;
                if app.prismpairOrder == "TOD"
                    syms H3(omega0) R3(omega0)
                    R3(omega0) = app.fRthird;
                    H3(omega0) = app.fRthird;
                    RR = subs(R3(omega0),omega0,app.omega0);
                    HH = subs(H3(omega0),omega0,app.omega0);
                else
                    syms H4(omega0) R4(omega0)
                    R4(omega0) = app.fRfourth;
                    H4(omega0) = app.fRfourth;
                    RR = subs(R4(omega0),omega0,app.omega0);
                    HH = subs(H4(omega0),omega0,app.omega0);
                end
                app.RTextArea.Value = string(vpa(10^3 *RR,5));
                app.HTextArea.Value = string(vpa(10^3 *HH,5));
                if (app.H == 0 && app.R == 0)||~app.flagValueChosen
                    app.H = HH;
                    app.R = RR;
                    if app.R >= 0
                        app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlPositive.png');
                    else
                        app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlNegative.png');  
                    end
                    app.controltext = "Prism Pair, R = " + string(round(app.R*10^3,4)) + "mm, H = " + string(round(app.H*10^3,4)) + "mm";
                    syms phi2prism(omega0, R, H) phi2control(omega0)
                    phi2prism(omega0, R, H) = app.phi2_prism
                    phi2control(omega0) = vpa(subs(phi2prism(omega0, R, H),{R, H},{app.R, app.H}));
                    app.phi2_control = phi2control(omega0);
                    ControlComponentGraph(app)           
                end
            else 
               dispersionControlWindow(app)
            end
        end
        function dispersionControlPrismPair(app)
            syms omega0 H R
            syms R2(H,omega0) H3(omega0) R3(omega0) H4(omega0) R4(omega0) phi2_prism(omega0, R, H)
            [R2(omega0,H),R3(omega0), H3(omega0), R4(omega0), H4(omega0), phi2_prism(omega0, R, H)] = ControlPrism(app.Prism_Ref_Index, app.UITablematerials.Data);
            app.fRsecend = R2(omega0,H); 
            app.fRthird = R3(omega0);
            app.fHthird = H3(omega0);
            app.fRfourth = R4(omega0);
            app.fHfourth = H4(omega0);
            app.phi2_prism = phi2_prism(omega0, R, H);
            app.omega0 = 6*pi/app.Wavelength *10^8;
            syms R20(H)
            R20(H) = vpa(subs(R2(omega0,H),omega0,app.omega0));
            R3 = subs(R3(omega0),omega0,app.omega0);
            H3 = subs(H3(omega0),omega0,app.omega0);
            R4 = subs(R4(omega0),omega0,app.omega0);
            H4 = subs(H4(omega0),omega0,app.omega0);
            app.RHTextArea.Value = string(vpa(10^3 *R20(H),5));
            RR = 0;
            HH = 0;
            if app.prismpairOrder == "TOD"
                RR = R3;
                HH = H3;
            else
                RR = R4;
                HH = H4;
            end
            app.RTextArea.Value = string(vpa(10^3 *RR,5));
            app.HTextArea.Value = string(vpa(10^3 *HH,5));
            if (app.H == 0 && app.R == 0)||~app.flagValueChosen
                app.H = HH;
                app.R = RR;
            end
            if app.R >= 0
                app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlPositive.png');
            else
                app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlNegative.png');  
            end
            app.controltext = "Prism Pair, R = " + string(round(app.R*10^3,4)) + "mm, H = " + string(round(app.H*10^3,4)) + "mm";
            syms phi2control(omega0)
            phi2control(omega0) = vpa(subs(phi2_prism(omega0, R, H),{R, H},{app.R, app.H}));
            app.phi2_control = phi2control(omega0);
            ControlComponentGraph(app)          
        end

        function dispersionControlWindow(app)
            syms omega0 L w
            syms L_2(omega0) phi2_win(w, L)
            [L_2(omega0),  phi2_win(w, L)] = ControlWindow(app.Prism_Ref_Index, app.UITablematerials.Data);
            app.fLsecend = L_2(omega0);
            app.phi2_window = phi2_win(w, L);
            app.omega0 = 6*pi/app.Wavelength *10^8;
            Length = subs(L_2(omega0),omega0,app.omega0);
            app.LTextArea.Value = string(vpa(Length*10^3,5));
            if app.L == 0||~app.flagValueChosen
                app.L = Length;
            end
            app.controltext = "Window, L = " + string(round(app.L*10^3,4)) + "mm";
            syms phi2control(omega0)
            phi2control(omega0) = vpa(subs(phi2_win(w, L),{w, L},{omega0, app.L}));
            app.phi2_control = phi2control(omega0);
            ControlComponentGraph(app)
        end

        function dispersionControlGratingPair(app)
            syms omega0 w L period ang phi2grating(w, L, period, ang)
            [phi2grating(w, L, period, ang)] = ControlGratingPair();
            syms phi(w) phi2total_grating(w,L,period,ang)
            phi(w) = app.fphi0TotalMaterials;
            phi2total_grating(w,L,period,ang) = diff(phi,w,2) + phi2grating(w, L, period, ang);
            app.phi2_total_grating = phi2total_grating(w,L,period,ang);
            app.phi2_grating = phi2grating(w, L, period, ang);
            if app.gratingL ~= 0 || app.gratingAngle ~= 0 || app.gratingPeriod ~= 0 
                app.controltext = "Grating Pair, L = " + string(round(app.gratingL *10^3,4)) + "mm, Period = " + string(round(app.gratingPeriod *10^-3,4)) + "mm^{-1}" + ", \theta = "+ string(round(app.gratingAngle,4)) + "^{\circ}";
                syms phi2control(omega0)
                phi2control(omega0) = vpa(subs(phi2grating(w, L, period, ang),{w, L, period, ang},{omega0, app.gratingL,app.gratingPeriod,app.gratingAngle}));
                app.phi2_control = phi2control(omega0);
                ControlComponentGraph(app)
            end
        end
        
        function refractiveIndex(app)
            syms w
            syms frefindex(w) GDD(w) TOD(w)
            c = 299792458;
            frefindex(w) = app.fref_index;
            txt = app.name_ref_index;
            rangew = 2*pi*c ./ app.WavelengthrangenmSliderSetUp.Value *10^-6;
            app.limitlamb = app.WavelengthrangenmSliderSetUp.Value *10^-3;
            app.limitw = sort(rangew);
            if app.axesIsw
                xlabel(app.UIAxesRefIndex, '\omega[PHz]')
                app.UIAxesRefIndex.XLim = app.limitw;
                if app.refgraphtype == "Refractive Index"
                    ylabel(app.UIAxesRefIndex, 'n(\omega)')
                    fplot(app.UIAxesRefIndex, real(frefindex(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
                elseif app.refgraphtype == "GDD"
                    GDD(w) = diff(frefindex(w)*w*app.length_ref_index*(10^-3) /c,2,w);
                    ylabel(app.UIAxesRefIndex, 'GDD(\omega)[fs^2]')
                    fplot(app.UIAxesRefIndex, (10^30)*GDD(w*10^15), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
                else
                    TOD(w) = diff(frefindex(w)*w*app.length_ref_index*(10^-3)/c,3,w);
                    ylabel(app.UIAxesRefIndex, 'TOD(\omega)[fs^3]')
                    fplot(app.UIAxesRefIndex, (10^45)*TOD(w*10^15), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
                end
            else 
                app.UIAxesRefIndex.XLim = app.limitlamb;
                xlabel(app.UIAxesRefIndex, '\lambda[µm]')
                syms lamb
                syms fref_index_l(lamb) GDD_l(lamb) TOD_l(lamb)
                if app.refgraphtype == "Refractive Index"
                    ylabel(app.UIAxesRefIndex, 'n(\lambda)')
                    fref_index_l(lamb) = subs(frefindex(w), w, 2*pi*c/lamb);
                    fplot(app.UIAxesRefIndex, fref_index_l(lamb*10^-6), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
                elseif app.refgraphtype == "GDD"
                    GDD(w) = diff(frefindex(w)*w*app.length_ref_index*(10^-3)/c,2);
                    GDD_l(lamb) = subs(GDD(w), w, 2*pi*c/lamb);
                    ylabel(app.UIAxesRefIndex, 'GDD(\lambda)[fs^2]')
                    fplot(app.UIAxesRefIndex, (10^30)*GDD_l(lamb*10^-6), app.limitlamb,'LineWidth', 1.5,'DisplayName', txt);
                else
                    TOD(w) = diff(frefindex(w)*w*app.length_ref_index*(10^-3)/c,3);
                    TOD_l(lamb) = subs(TOD(w), w, 2*pi*c/lamb);
                    ylabel(app.UIAxesRefIndex, 'TOD(\lambda)[fs^3]')
                    fplot(app.UIAxesRefIndex, (10^45)*TOD_l(lamb*10^-6), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
                end
            end
            legend(app.UIAxesRefIndex);
        end
        
        function refractiveIndexPrism(app)
            syms w
            syms fref_index(w)
            fref_index(w) = RefractiveIndex(app.Prism_Ref_Index);
            app.fref_index_prism = fref_index(w);
            txt = app.Prism_name;
            if app.axesIsw
                xlabel(app.UIAxesPrismRI, '\omega[PHz]')
                ylabel(app.UIAxesPrismRI, 'n(\omega)')
                app.UIAxesPrismRI.XLim = app.limitw;
                fplot(app.UIAxesPrismRI, abs(fref_index(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            else 
                app.UIAxesPrismRI.XLim = app.limitlamb;
                xlabel(app.UIAxesPrismRI, '\lambda[µm]')
                ylabel(app.UIAxesPrismRI, 'n(\lambda)')
                c = 299792458;
                syms lamb
                syms fref_index_l(lamb)
                fref_index_l(lamb) = subs(fref_index(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesPrismRI, abs(fref_index_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesPrismRI);
        end
        
        function saveGraph(app, uiaxes)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.eps'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(uiaxes,[filepath filename],'Resolution',400);
            end
            
        end
        
        function totalDispersion(app)
            syms w omega0
            syms fdis_components(w,omega0)
            fdis_components(w,omega0) = DispersionFunction(app.UITablematerials.Data, app.Order);
            app.fdispersion = fdis_components(w,omega0);
            syms R H
            syms fdis_prism(w,R,H,omega0)
            fdis_prism(w,R,H,omega0) = PrismPairDispersion(app.Prism_Ref_Index, app.Order);
            app.fdis_prism = fdis_prism(w,R,H,omega0);
            syms fdis_total(w,R,H,omega0)
            fdis_total(w,R,H,omega0) = fdis_components(w,omega0) + fdis_prism(w,R,H,omega0);
            app.ftotat_dispersion = fdis_total(w,R,H,omega0);
            app.omega0 = 6*pi/app.Wavelength *10^8;
            fdis_total(w,R,H) = subs(fdis_total(w,R,H,omega0), omega0, app.omega0);
            fdis_total(w,R) = subs(fdis_total(w,R,H), H, app.H * 10^-3);
            fdis_total(w) = subs(fdis_total(w,R), R, app.R *10^-3);
            app.UIAxesTotalDispersion.XLim = app.limitw;
            fplot(app.UIAxesTotalDispersion,abs(fdis_total(w*10^9)), app.limitw, 'LineWidth', 1.5);
        end
        
        function changeAsfunction(app, value)
            app.asfunctionofSwitch_7.Value = value;
            app.asfunctionofSwitch_5.Value = value;
            app.asfunctionofSwitch_4.Value = value;
            app.asfunctionofSwitch_3.Value = value;
        end
        function changeRange(app, value)
            app.WavelengthrangenmSliderSetUp.Value = value;
            app.WavelengthrangenmSliderDispersion.Value = value;
            app.WavelengthrangenmSliderControlComponent.Value = value;
            app.WavelengthrangenmSliderControl.Value = value;
        end
        function changeOrder(app,order)
            app.orderSpinner.Value = order;
            app.orderSpinner_3.Value = order;
        end
        function changePODorder(app,order)
            app.DispersionParameterorderSpinnerTotal.Value = order;
            app.DispersionParameterorderSpinner.Value = order;
        end
        %wavelength in nm unit
        function changeWavelength(app, wavelength0)
            if wavelength0*10^-3 < app.limitlamb(1) ||  wavelength0*10^-3 > app.limitlamb(2)
                app.Wavelength = interp1(app.limitlamb, app.limitlamb, wavelength0*10^-3, 'nearest', 'extrap')*10^-6;
                app.omega0 = 6*pi/app.Wavelength *10^8;
            else
                app.Wavelength = wavelength0*10^-9;
                app.omega0 = 6*pi/app.Wavelength *10^8;
            end
            app.wavelengthinfreespacenmSlider.Value = app.Wavelength*10^9;
            app.wavelengthinfreespacenmSliderControl.Value = app.Wavelength*10^9;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectedCompenentslog=[];
            app.Order = app.orderSpinner.Value;
            app.groupDropDown.Value = '';
            app.subgroupDropDown.Value = '';
            app.materialnameDropDown.Value = '';
            app.groupprismDropDown.Value = '';
            app.subgroupprismDropDown.Value = '';
            app.materialnameprismDropDown.Value = '';
            app.fdispersion = 0;
            app.fref_index = 0;
            app.fref_index_prism = 0;
            app.ftotat_dispersion = 0;
            app.fdis_prism = 0;
            app.fRsecend = 0;
            app.fRthird = 0;
            app.fHthird = 0;
            app.fRfourth = 0;
            app.fHfourth = 0;
            app.phi2_prism = 0;
            app.phi2_window = 0;
            app.phi2_grating = 0;
            app.groupDropDown.Items = dbShelfs();
            app.groupprismDropDown.Items = app.groupDropDown.Items;
            app.UITablematerials.RowName = 'numbered';
            app.limitw = [1 7];
            app.limitlamb = [4*10^-1 16*10^-1];
            app.UITablematerials.SelectionType = 'row';
            app.UITablematerials.Multiselect = 'off';
            app.SelectedMaterialDelRow = 0;
            app.isprism = false;
            app.name_ref_index = '';
            app.length_ref_index = 0;
            app.str_ref_index = '';
            app.page = '';
            app.ref_index = '';
            app.Order = 3;
            app.Wavelength = 0.623*10^-6;
            app.omega0 = 6*pi/app.Wavelength *10^8;
            app.axesIsw = true;
            app.refgraphtype = app.DropDown.Value;
            app.POD_order = app.DispersionParameterorderSpinner.Value;
            app.selectedControl = app.DispersionControlByButtonGroup.SelectedObject.Text;
            app.Col1File = '';
            app.Col2File = '';
            app.isFile = 1;
            app.Col1Unit = 'MHz';
            app.Col2Unit = 'fs';
            app.isFileUploaded = 0;
            app.Col1Data = [];
            app.Col2Data = [];
            app.FileGdd = [];
            app.FileTod = [];
            app.phi2_control = 0;
            app.controlgraphtype = "GDD";
            app.controltext = '';
            app.H = 0;
            app.R = 0;
            app.L = 0;
            app.gratingL = 0;
            app.gratingAngle = 0;
            app.gratingPeriod = 0;
            app.fphiTotalMaterials = 0;
            app.fphi0TotalMaterials = 0;
            app.flagValueChosen = false;
            app.prismpairOrder = "TOD";
            app.checkboxGrating = "Nall";
            app.phi2_total_grating = 0;
        end

        % Value changed function: addelementButton
        function addelementButtonValueChanged(app, event)
            flag = false;
            if app.materialSwitch.Value == "New Material"
                app.page = app.nameEditField.Value;
                ref = app.RefractiveindexEditField.Value;
                if strcmp(ref, "")
                    flag = true;
                    app.noterefindex.Visible ="on";
                else
                    if contains(ref,'lamb') && contains(ref,'w')
                        app.noterefindex.Visible="on";
                        app.noterefindexcontain.Visible = "off";
                        flag = true;
                    else
                        app.noterefindex.Visible ="off";
                        str = erase(ref,'w');
                        str = erase(str,'lamb');
                        str = erase(str,'sqrt');
                        [X,Y]=regexp(str,'[+-/*^()]', 'match','split');
                        Y = strtrim(Y);
                        str = [Y{:}];
                        refdouble = str2double(str);
                        if isnan(refdouble)
                            app.noterefindexcontain.Visible="on";
                            flag = true;
                        else
                            app.ref_index = ref;
                            app.noterefindexcontain.Visible="off";
                        end
                    end
                end
            else
                if strcmp(app.page,"")
                    app.notematerial.Visible = "on";
                    flag = true;
                else
                    app.notematerial.Visible = "off";
                end
            end
            length = app.lengthEditField.Value;
            if strcmp(length, "")
                app.notelength.Visible = "on";
                app.notelengthnumber.Visible = "off";
                flag = true;
            else
                app.notelength.Visible = "off";
                length2 = str2double(length);
                if isnan(length2)
                    app.notelengthnumber.Visible = "on";
                    app.lengthEditField.Value = '';
                    flag = true;
                else
                    app.notelengthnumber.Visible = "off";  
                end
            end
            if flag == false
                app.nameEditField.Value = '';
                app.RefractiveindexEditField.Value = '';
                app.lengthEditField.Value = '';
                app.groupDropDown.Value = '';
                app.subgroupDropDown.Value = '';
                app.subgroupDropDown.Items = {};
                app.materialnameDropDown.Value = '';
                app.materialnameDropDown.Items = {};
                material = {app.page, length, app.ref_index};
                app.UITablematerials.Data = [app.UITablematerials.Data; material];
                app.materialSwitch.Value = "From Database";
                materialSwitchValueChanged(app);
                app.name_ref_index = app.page;
                app.str_ref_index = app.ref_index;
                app.length_ref_index = str2double(length);
                syms w
                syms fref_index(w)
                fref_index(w) = RefractiveIndex(app.str_ref_index);
                app.fref_index = fref_index(w);
                refractiveIndex(app);
                app.ref_index = '';
                app.page = ''; 
                scroll(app.UITablematerials, 'bottom')
            end
        end

        % Value changed function: wavelengthinfreespacenmSlider
        function wavelengthinfreespacenmSliderValueChanged(app, event)
            changeWavelength(app,app.wavelengthinfreespacenmSlider.Value);
            syms w omega0
            syms fphi(w,omega0) phi(w)
            phi(w) = app.fphic;
            app.PODTextArea.Value = strcat(string(double(subs(diff(phi, app.POD_order),w,app.omega0)*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']')
            fphi(w, omega0) = app.fdispersion;
            fphi(w) = subs(fphi, omega0, app.omega0);
            txt = "\lambda_0 =  ";
            txt = strcat(txt, int2str(app.Wavelength*10^9));
            txt = strcat(txt, "nm, order = ");
            txt = strcat(txt, int2str(app.Order));
            if app.axesIsw   
                app.UIAxesDispersion.XLim = app.limitw;
                fplot(app.UIAxesDispersion, abs(fphi(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            else 
                app.UIAxesDispersion.XLim = app.limitlamb;
                c = 299792458;
                syms lamb
                syms fphi_l(lamb)
                fphi_l(lamb) = subs(fphi(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesDispersion, abs(fphi_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesDispersion);
        end

        % Button pushed function: DeletematerialButton
        function DeleteButtonPushed(app, event)
            rn = str2double(app.rownumberEditField.Value);
            if rn == 0 || app.rownumberEditField.Value == ""
                rn = app.SelectedMaterialDelRow;
                app.SelectedMaterialDelRow = 0;
            else
                app.rownumberEditField.Value = "";
            end
            if height(app.UITablematerials.Data) >= rn && rn > 0
                app.UITablematerials.Data(rn,:) = [];
            end
        end

        % Callback function
        function changecontrolComponentmaterialButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.DispersionControlComponentTab;
        end

        % Value changed function: orderSpinner
        function orderSpinnerValueChanged(app, event)
            changeOrder(app,app.orderSpinner.Value);
            app.Order = app.orderSpinner.Value;
            syms w omega0
            syms fphi(w,omega0)
            fphi(w,omega0) = DispersionFunction(app.UITablematerials.Data, app.Order);
            app.fdispersion = fphi(w,omega0);
            fphi(w) = subs(fphi, omega0, app.omega0);
            txt = "\lambda_0 =  ";
            txt = strcat(txt, int2str(app.Wavelength*10^9));
            txt = strcat(txt, "nm, order = ");
            txt = strcat(txt, int2str(app.Order));
            if app.axesIsw   
                app.UIAxesDispersion.XLim = app.limitw;
                fplot(app.UIAxesDispersion, abs(fphi(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            else 
                app.UIAxesDispersion.XLim = app.limitlamb;
                c = 299792458;
                syms lamb
                syms fphi_l(lamb)
                fphi_l(lamb) = subs(fphi(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesDispersion, abs(fphi_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesDispersion);
        end

        % Value changed function: groupDropDown
        function groupDropDownValueChanged(app, event)
            app.group = app.groupDropDown.Value;
            app.book = '';
            app.page = '';
            app.subgroupDropDown.Items = dbBooks(app.group);
        end

        % Value changed function: subgroupDropDown
        function subgroupDropDownValueChanged(app, event)
            app.book = app.subgroupDropDown.Value;
            app.page = '';
            app.materialnameDropDown.Items = dbPages(app.group, app.book);
        end

        % Value changed function: materialnameDropDown
        function materialnameDropDownValueChanged(app, event)
            app.page = app.materialnameDropDown.Value;
            app.ref_index = dbFormula(app.page, app.book);
        end

        % Value changed function: materialSwitch
        function materialSwitchValueChanged(app, event)
            value = app.materialSwitch.Value;
            if value == "From Database"
                app.groupDropDown.Visible ="on";
                app.groupDropDownLabel.Visible ="on";
                app.subgroupDropDown.Visible ="on";
                app.subgroupDropDownLabel.Visible ="on";
                app.materialnameDropDown.Visible ="on";
                app.materialnameDropDownLabel.Visible ="on";
                app.nameEditField.Visible = "off";
                app.nameEditFieldLabel.Visible = "off";
                app.RefractiveindexEditField.Visible = "off";
                app.RefractiveindexEditFieldLabel.Visible = "off";
            else
                app.groupDropDown.Visible ="off";
                app.groupDropDownLabel.Visible ="off";
                app.subgroupDropDown.Visible ="off";
                app.subgroupDropDownLabel.Visible ="off";
                app.materialnameDropDown.Visible ="off";
                app.materialnameDropDownLabel.Visible ="off";
                app.nameEditField.Visible = "on";
                app.nameEditFieldLabel.Visible = "on";
                app.RefractiveindexEditField.Visible = "on";
                app.RefractiveindexEditFieldLabel.Visible = "on";
            end
            app.noterefindexcontain.Visible="off";
            app.noterefindex.Visible ="off";
            app.notelength.Visible ="off";
            app.notelengthnumber.Visible ="off";
            app.notematerial.Visible ="off";
        end

        % Button pushed function: CalculateDispersionButton
        function CalculateDispersionButtonPushed(app, event)
            fig = app.UIFigure;
            if height(app.UITablematerials.Data) == 0
                app.TabGroup.SelectedTab = app.SetupTab; 
                uialert(fig,'Please Add Optical Component First','Warning','Icon','warning');
            else
                app.TabGroup.SelectedTab = app.DispersionFunctionTab;
                app.SelectedCompenentslog = logical(ones(height(app.UITablematerials.Data),1));
                dispersionFunction(app)
            end
        end

        % Button down function: DispersionFunctionTab
        function DispersionFunctionTabButtonDown(app, event)
            fig = app.UIFigure;
            if height(app.UITablematerials.Data) == 0
                app.TabGroup.SelectedTab = app.SetupTab; 
                uialert(fig,'Please Add Optical Component First','Warning','Icon','warning');
            else
                app.SelectedCompenentslog = logical(ones(height(app.UITablematerials.Data),1));
                dispersionFunction(app)
            end
            
        end

        % Callback function
        function cleangraphButtonPushed(app, event)
            cla(app.UIAxesDispersion);
        end

        % Cell selection callback: UITablematerials
        function UITablematerialsCellSelection(app, event)
            try
                app.SelectedMaterialDelRow = event.Indices(1,1);
                app.name_ref_index = app.UITablematerials.Data{app.SelectedMaterialDelRow,1};
                app.str_ref_index = app.UITablematerials.Data{app.SelectedMaterialDelRow,3};
                syms w
                syms fref_index(w)
                fref_index(w) = RefractiveIndex(app.str_ref_index);
                app.fref_index = fref_index(w);
                refractiveIndex(app);
            catch ME
            end
        end

        % Selection changed function: UITablelistmaterials
        function UITablelistmaterialsSelectionChanged(app, event)

        end

        % Display data changed function: UITablelistmaterials
        function UITablelistmaterialsDisplayDataChanged(app, event)
            app.SelectedCompenentslog = table2array(app.UITablelistmaterials.Data(:,1));
            dispersionFunction(app)
        end

        % Value changed function: materialPrismSwitch
        function materialPrismSwitchValueChanged(app, event)
            value = app.materialPrismSwitch.Value;
            if value == "From Database"
                app.groupprismDropDown.Visible ="on";
                app.groupprismDropDown_Label.Visible ="on";
                app.subgroupprismDropDown.Visible ="on";
                app.subgroupprismDropDown_Label.Visible ="on";
                app.materialnameprismDropDown.Visible ="on";
                app.materialnameprismDropDown_Label.Visible ="on";
                app.nameprismEditField.Visible = "off";
                app.nameprismEditField_Label.Visible = "off";
                app.RefractiveindexprismEditField.Visible = "off";
                app.RefractiveindexprismEditField_Label.Visible = "off";
            else
                app.groupprismDropDown.Visible ="off";
                app.groupprismDropDown_Label.Visible ="off";
                app.subgroupprismDropDown.Visible ="off";
                app.subgroupprismDropDown_Label.Visible ="off";
                app.materialnameprismDropDown.Visible ="off";
                app.materialnameprismDropDown_Label.Visible ="off";
                app.nameprismEditField.Visible = "on";
                app.nameprismEditField_Label.Visible = "on";
                app.RefractiveindexprismEditField.Visible = "on";
                app.RefractiveindexprismEditField_Label.Visible = "on";
            end
            app.noterefindexprism.Visible ="off";
            app.noterefindexcontainprism.Visible ="off";
            app.notematerialprism.Visible ="off";
        end

        % Value changed function: groupprismDropDown
        function groupprismDropDownValueChanged(app, event)
            app.group = app.groupprismDropDown.Value;
            g = dbBooks(app.group);
            if ~isempty(g)
                app.subgroupprismDropDown.Items = dbBooks(app.group);
            end
        end

        % Value changed function: subgroupprismDropDown
        function subgroupprismDropDownValueChanged(app, event)
            app.book = app.subgroupprismDropDown.Value;
            p = dbPages(app.group, app.book);
            if ~isempty(p)
                app.materialnameprismDropDown.Items = p;
            end
        end

        % Value changed function: materialnameprismDropDown
        function materialnameprismDropDownValueChanged(app, event)
            app.page = app.materialnameprismDropDown.Value;
            app.Prism_Ref_Index = dbFormula(app.page, app.book);
        end

        % Value changed function: addprismmaterialButton
        function addprismmaterialButtonValueChanged(app, event)
            flag = false;
            if app.materialPrismSwitch.Value == "New Material"
                app.page = app.nameprismEditField.Value;
                ref = app.RefractiveindexprismEditField.Value;
                if strcmp(ref, "") 
                    flag = true;
                    app.noterefindexprism.Visible ="on";
                else
                    if contains(ref,'lamb') && contains(ref,'w')
                        app.noterefindexprism.Visible="on";
                        app.noterefindexcontainprism.Visible="off";
                        flag = true;
                    else
                        app.noterefindexprism.Visible ="off";
                        str = erase(ref,'w');
                        str = erase(str,'lamb');
                         str = erase(str,'sqrt');
                        [X,Y]=regexp(str,'[+-/*^()]', 'match','split');
                        Y = strtrim(Y);
                        str = [Y{:}];
                        refdouble = str2double(str);
                        if isnan(refdouble)
                            app.noterefindexcontainprism.Visible="on";
                            flag = true;
                        else
                            app.Prism_Ref_Index = ref;
                            app.noterefindexcontainprism.Visible="off";
                        end
                    end
                end
            else
                if strcmp(app.page,"")
                    app.notematerialprism.Visible = "on";
                    flag = true;
                else
                    app.Prism_name = app.page;
                    app.notematerialprism.Visible = "off";
                end
            end
            if flag == false
                app.nameprismEditField.Value = '';
                app.RefractiveindexprismEditField.Value = ''; 
                app.groupprismDropDown.Value = '';
                app.subgroupprismDropDown.Value = '';
                app.subgroupprismDropDown.Items = {};
                app.materialnameprismDropDown.Value = '';
                app.materialnameprismDropDown.Items = {};
                app.materialPrismSwitch.Value = "From Database";
                materialPrismSwitchValueChanged(app);
                app.isprism = true;
                app.page = ""; 
                refractiveIndexPrism(app);
            end
        end

        % Button pushed function: DispersionControlButton
        function DispersionControlButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DispersionControlTab;
            DispersionControlTabButtonDown(app, event);
        end

        % Callback function
        function savegraphimageButtonPushed(app, event)
            exportapp(app.UIFigure, 'C:\Users\name\Documents\Matlab\AppImage.png')
        end

        % Button pushed function: CalculateDispersionControlButton
        function CalculateDispersionControlButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DispersionControlTab;
            DispersionControlTabButtonDown(app, event);
        end

        % Callback function
        function MaterialRefrectiveIndexInformationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.RefractiveIndexTab;
            app.choosematerialListBox.Items = unique(app.UITablematerials.Data(:,1));
        end

        % Button pushed function: resetgraphButton
        function resetgraphButtonPushed(app, event)
               cla(app.UIAxesRefIndex);
        end

        % Value changed function: asfunctionofSwitch_3
        function asfunctionofSwitch_3ValueChanged(app, event)
            changeAsfunction(app,app.asfunctionofSwitch_3.Value)
            cla(app.UIAxesRefIndex);
            if app.asfunctionofSwitch_3.Value == "λ"
                app.axesIsw = false;
                app.UIAxesRefIndex.XLim = app.limitlamb;
            else
                app.axesIsw = true;
                app.UIAxesRefIndex.XLim = app.limitw;
            end
            refractiveIndex(app)
        end

        % Button pushed function: savegraphButton
        function savegraphButtonPushed(app, event)
            saveGraph(app,app.UIAxesRefIndex);
        end

        % Button pushed function: resetgraphButton_2
        function resetgraphButton_2Pushed(app, event)
            cla(app.UIAxesDispersion);
        end

        % Button pushed function: savegraphButton_2
        function savegraphButton_2Pushed(app, event)
            saveGraph(app,app.UIAxesDispersion);
        end

        % Value changed function: asfunctionofSwitch_4
        function asfunctionofSwitch_4ValueChanged(app, event)
            changeAsfunction(app,app.asfunctionofSwitch_4.Value)
            cla(app.UIAxesDispersion);
            syms w omega0
            syms fphi(w,omega0)
            fphi(w, omega0) = app.fdispersion;
            fphi(w) = subs(fphi, omega0, app.omega0);
            txt = "\lambda_0 =  ";
            txt = strcat(txt, int2str(app.Wavelength*10^9));
            txt = strcat(txt, "nm, order = ");
            txt = strcat(txt, int2str(app.Order));
            if app.asfunctionofSwitch_4.Value == "λ"
                app.axesIsw = false;
                app.UIAxesDispersion.XLim = app.limitlamb;
                xlabel(app.UIAxesDispersion, '\lambda[µm]')
                ylabel(app.UIAxesDispersion, '\phi(\lambda)')
                c = 299792458;
                syms lamb
                syms fphi_l(lamb)
                fphi_l(lamb) = subs(fphi(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesDispersion, abs(fphi_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            else
                app.axesIsw  = true;
                xlabel(app.UIAxesDispersion, '\omega[PHz]')
                ylabel(app.UIAxesDispersion, '\phi(\omega)')
                app.UIAxesDispersion.XLim = app.limitw;
                fplot(app.UIAxesDispersion, abs(fphi(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesDispersion);
        end

        % Button pushed function: resetgraphButton_3
        function resetgraphButton_3Pushed(app, event)
            cla(app.UIAxesPrismRI);
        end

        % Button pushed function: savegraphButton_3
        function savegraphButton_3Pushed(app, event)
            saveGraph(app, app.UIAxesPrismRI);
        end

        % Button down function: DispersionControlComponentTab
        function DispersionControlComponentTabButtonDown(app, event)
            
        end

        % Button down function: DispersionControlTab
        function DispersionControlTabButtonDown(app, event)
            app.Order = app.orderSpinner_3.Value;
            syms w omega0
            syms fphi(w,omega0) phi(w) 
            [fphi(w,omega0), phi(w)] = DispersionFunction(app.UITablematerials.Data, app.Order);
            app.fphiTotalMaterials = fphi(w,omega0);
            app.fphi0TotalMaterials = phi(w);
            if (app.selectedControl == "Prism Pair" || app.selectedControl == "Window") && app.isprism == false
                app.TabGroup.SelectedTab = app.DispersionControlComponentTab;
            else
                dispersionControl(app)
            end
        end

        % Callback function
        function CalculateDispesionButtonPushed(app, event)
            flag = false;
            R = app.ParismdistanceREditField.Value;
            if strcmp(R, "")
                app.RnoteLable.Visible = "on";
                flag = true;
            else
                app.RnoteLable.Visible = "off";
            end
            H = app.ParismdistanceREditField.Value;
            if strcmp(H, "")
                app.HnoteLable.Visible = "on";
                flag = true;
            else
                app.HnoteLable.Visible = "off";
            end
            if flag == false
                app.ParismdistanceREditField.Value = '';
                app.ParismdistanceREditField.Value = ''; 
                app.R = R;
                app.H = H;
                totalDispersion(app);
            end
        end

        % Callback function
        function orderSpinner_2ValueChanged(app, event)

        end

        % Button down function: SetupTab
        function SetupTabButtonDown(app, event)
            
        end

        % Callback function
        function savegraphButton_4Pushed(app, event)
             saveGraph(app,app.UIAxesTotalDispersion);
        end

        % Value changed function: asfunctionofSwitch_5
        function asfunctionofSwitch_5ValueChanged(app, event)
            changeAsfunction(app,app.asfunctionofSwitch_5.Value);
            cla(app.UIAxesPrismRI);
            syms w
            syms fref_index(w)
            fref_index(w) = app.fref_index_prism;
            txt = "";
            txt = strcat(txt, app.Prism_name);
            if app.asfunctionofSwitch_5.Value == "λ"
                app.axesIsw = false;
                app.UIAxesPrismRI.XLim = app.limitlamb;
                xlabel(app.UIAxesPrismRI, '\lambda[µm]')
                ylabel(app.UIAxesPrismRI, 'n(\lambda)')
                c = 3 *10^8;
                syms lamb
                syms fref_index_l(lamb)
                fref_index_l(lamb) = subs(fref_index(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesPrismRI, abs(fref_index_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            else
                app.axesIsw  = true;
                xlabel(app.UIAxesPrismRI, '\omega[PHz]')
                ylabel(app.UIAxesPrismRI, 'n(\omega)')
                app.UIAxesPrismRI.XLim = app.limitw;
                fplot(app.UIAxesPrismRI, abs(fref_index(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesPrismRI);
        end

        % Callback function
        function resetgraphButton_4Pushed(app, event)
            cla(app.UIAxesTotalDispersion);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.refgraphtype = app.DropDown.Value;
            cla(app.UIAxesRefIndex);
            refractiveIndex(app);
        end

        % Value changed function: DispersionParameterorderSpinner
        function DispersionParameterorderSpinnerValueChanged(app, event)
            app.POD_order = app.DispersionParameterorderSpinner.Value;
            changePODorder(app,app.POD_order)
            syms w
            syms phi(w)
            phi(w) = app.fphic;
            app.PODTextArea.Value = strcat(string(double(subs(diff(phi, app.POD_order),w,app.omega0)*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']');
        end

        % Value changed function: wavelengthinfreespacenmSliderControl
        function wavelengthinfreespacenmSliderControlValueChanged(app, event)
            changeWavelength(app,app.wavelengthinfreespacenmSliderControl.Value);
            dispersionControlOmega0Chamged(app)
            TotalGraph(app)
        end

        % Button pushed function: uploadExelFileButton
        function uploadExelFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            c = 299792458;
            if isequal(file,0)               
               msgbox('Please input an Excel file')
            else
                app.isFileUploaded = 1;
                app.Col1Unit = app.UnitsDropDownCol1.Value;
                app.Col2Unit = app.UnitsDropDownCol2.Value;
                t = readtable(fullfile(path, file));     
                if app.Col1DropDown.Value == "w values"
                    app.Col1File = 'omega';
                    changeAsfunction(app,"ω");
                    app.axesIsw = true;
                    if app.Col1Unit == "MHz"
                        app.Col1Data =  t.(1) *10^6;
                    elseif app.Col1Unit == "GHz"
                        app.Col1Data =  t.(1) *10^9;
                    elseif app.Col1Unit == "THz"
                        app.Col1Data =  t.(1) *10^12;
                    else 
                        app.Col1Data =  t.(1) *10^15;
                    end
                else 
                    app.Col1File = 'lambda';
                    changeAsfunction(app,"λ");
                    app.axesIsw = false;
                    if app.Col1Unit == "micrometer"
                        app.Col1Data =  t.(1) *10^-6;
                    elseif app.Col1Unit == "nm"
                        app.Col1Data =  t.(1) *10^-9;
                    elseif app.Col1Unit == "pm"
                        app.Col1Data =  t.(1) *10^-12;
                    else 
                        app.Col1Data =  t.(1) *10^-15;
                    end
                end
                if app.Col2DropDown.Value == "Group Delay"
                    app.Col2File = 'GD';
                    if app.Col2Unit == "ns"
                        app.Col2Data =  t.(2) *10^-9;
                    elseif app.Col2Unit == "ps"
                        app.Col2Data =  t.(2) *10^-12;
                    elseif app.Col2Unit == "fs"
                        app.Col2Data =  t.(2) *10^-15;
                    end
                    if app.Col1File == "omega"
                        dw = app.Col1Data(2:end) - app.Col1Data(1:end-1);
                        d2_phi = app.Col2Data(2:end) - app.Col2Data(1:end-1);
                        app.FileGdd =  d2_phi./dw;
                        d3_phi = app.FileGdd(2:end) - app.FileGdd(1:end-1);
                        app.FileTod =  d3_phi./dw(1:end-1);
                    else
                        dlamb = app.Col1Data(2:end) - app.Col1Data(1:end-1);
                        d2_phi = app.Col2Data(2:end) - app.Col2Data(1:end-1);
                        app.FileGdd =  -app.Col1Data(1:end-1).^2 /(2*pi*c) .*d2_phi./dlamb;
                        d3_phi = app.FileGdd(2:end) - app.FileGdd(1:end-1);
                        app.FileTod =  -app.Col1Data(1:end-2).^2 /(2*pi*c) .*d3_phi./dlamb(1:end-1);
                    end
                else
                    app.Col2File = 'GDD';
                    if app.Col2Unit == "ns"
                        app.Col2Data =  t.(2) *10^-18;
                    elseif app.Col2Unit == "ps"
                        app.Col2Data =  t.(2) *10^-24;
                    elseif app.Col2Unit == "fs"
                        app.Col2Data =  t.(2) *10^-30;
                    end
                    if app.Col1File == "omega"
                        dw = app.Col1Data(2:end) - app.Col1Data(1:end-1);
                        app.FileGdd =  app.Col2Data(1:end-1);
                        d3_phi = app.Col2Data(2:end) - app.Col2Data(1:end-1);
                        app.FileTod =  d3_phi(1:end-1)./dw(1:end-1);
                    else
                        dlamb = app.Col1Data(2:end) - app.Col1Data(1:end-1);
                        app.FileGdd =  app.Col2Data(1:end-1);
                        d3_phi = app.FileGdd(2:end) - app.FileGdd(1:end-1); 
                        app.FileTod =  -app.Col1Data(1:end-2).^2 /(2*pi*c) .*d3_phi./dlamb(1:end-1);
                    end
                end
                dispersionControl(app)
            end
        end

        % Button down function: Panel_prism
        function Panel_prismButtonDown(app, event)
            
        end

        % Selection changed function: DispersionControlByButtonGroup
        function DispersionControlByButtonGroupSelectionChanged(app, event)
            app.selectedControl = app.DispersionControlByButtonGroup.SelectedObject.Text;
            app.isFile = 0;
            app.phi2_control = 0;
            app.flagValueChosen = false;
            if app.selectedControl == "Dispersive Mirror"
                app.Panel_mirror.Visible = "on";
                app.Panel_prism.Visible = "off";
                app.Panel_window.Visible = "off";
                app.Panel_graiting.Visible = "off";
                app.isFile = 1;
                dispersionControl(app)
            elseif app.selectedControl == "Grating pair"
                app.Panel_mirror.Visible = "off";
                app.Panel_prism.Visible = "off";
                app.Panel_window.Visible = "off";
                app.Panel_graiting.Visible = "on";
                dispersionControl(app)
            elseif app.selectedControl == "Prism Pair"
                app.Panel_mirror.Visible = "off";
                app.Panel_prism.Visible = "on";
                app.Panel_window.Visible = "off";
                app.Panel_graiting.Visible = "off";
                if app.isprism == false
                    app.TabGroup.SelectedTab = app.DispersionControlComponentTab;
                else
                    dispersionControl(app)
                end
            else 
                app.Panel_mirror.Visible = "off";
                app.Panel_prism.Visible = "off";
                app.Panel_window.Visible = "on";
                app.Panel_graiting.Visible = "off";
                if app.isprism == false
                    app.TabGroup.SelectedTab = app.DispersionControlComponentTab;
                else
                    dispersionControl(app)
                end
            end
        end

        % Button pushed function: CalculateButtonPrism
        function CalculateButtonPrismPushed(app, event)
            if isnan(str2double(app.REditField.Value)) || isnan(str2double(app.HEditField.Value))
                app.notelengthnumberprism.Visible = "on";
            else
                app.flagValueChosen = true;
                app.notelengthnumberprism.Visible = "off";
                app.R = 10^-3 *str2double(app.REditField.Value);
                app.H = 10^-3 *str2double(app.HEditField.Value);
                if app.R >= 0
                    app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlPositive.png');
                else
                    app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlNegative.png');  
                end
                app.controltext = "Prism Pair, R = " + string(round(app.R *10^3,4)) + "mm, H = " + string(round(app.H*10^3,4)) + "mm";
                syms omega0 H R
                syms phi2prism(omega0, R, H) phi2control(omega0)
                phi2prism(omega0, R, H) = app.phi2_prism;
                phi2control(omega0) = vpa(subs(phi2prism(omega0, R, H),{R, H},{app.R, app.H}));
                app.phi2_control = phi2control(omega0);
                ControlComponentGraph(app)
                TotalGraph(app)
            end
        end

        % Value changed function: DropDownControlComponent
        function DropDownControlComponentValueChanged(app, event)
            app.controlgraphtype = app.DropDownControlComponent.Value;
            cla(app.UIAxesControlComponent);
            ControlComponentGraph(app);
        end

        % Button pushed function: CalculateButtonWindow
        function CalculateButtonWindowPushed(app, event)
            if isnan(str2double(app.LEditField.Value))
                app.notelengthnumberwindow.Visible = "on";
            else
                app.flagValueChosen = true;
                app.notelengthnumberwindow.Visible = "off";
                app.L = 10^-3 *str2double(app.LEditField.Value);
                app.controltext = "Window, L = " + string(round(app.L *10^3,4)) + "mm";
                syms omega0 H R w
                syms phi2win(w, L) phi2control(omega0)
                phi2win(w, L) = app.phi2_window;
                phi2control(omega0) = vpa(subs(phi2win(w, L),{w, L},{omega0, app.L}));
                app.phi2_control = phi2control(omega0);
                ControlComponentGraph(app)
                TotalGraph(app)
            end
        end

        % Value changed function: CalculateButtonGrating
        function CalculateButtonGratingValueChanged(app, event)
            syms omega0 w L period ang
            syms phi2grating(w, L, period, ang) phi2control(omega0)
            phi2grating(w, L, period, ang) = app.phi2_grating;
            flag = false;
            app.notelengthnumbergrating_2.Visible = "off";
            if app.checkboxGrating ~= "Distance" && isnan(str2double(app.DistanceLEditField.Value))
                flag = true;
            else
                app.gratingL = 10^-3 *str2double(app.DistanceLEditField.Value);
            end
            if app.checkboxGrating ~= "angle" && isnan(str2double(app.angleofincidenceEditField.Value))
                flag = true;
            else
                app.gratingAngle = str2double(app.angleofincidenceEditField.Value);
            end
            if app.checkboxGrating ~= "Period" && isnan(str2double(app.GratingPeriodEditField.Value))
                flag = true;
            else
                app.gratingPeriod = 10^3 *str2double(app.GratingPeriodEditField.Value);
            end
            if flag
                app.notelengthnumbergrating.Visible = "on";
            else
                app.notelengthnumbergrating.Visible  = "off";
                flagNoResult = false;
                syms phi(w) phi2(w) phi2total_grating(w,L,period,ang)
                phi2total_grating(w,L,period,ang) = app.phi2_total_grating;
                phi2total_grating(L,period,ang) = vpa(subs(phi2total_grating(w,L,period,ang),w,app.omega0));
                if app.checkboxGrating == "Distance"
                    app.gratingL = vpa(solve([subs(phi2total_grating(L,period,ang),{period,ang},{app.gratingPeriod,app.gratingAngle}) == 0],L));
                    app.DistanceLEditField.Value = string(round(10^3 *app.gratingL,4));
                elseif app.checkboxGrating == "Period"
                    assume(period,"positive")
                    gP = vpasolve([subs(phi2total_grating(L,period,ang),{L,ang},{app.gratingL,app.gratingAngle}) == 0, period>0],period);
                    gP = real(gP(imag(gP) == 0));
                    if isempty(gP)
                        flagNoResult = true;
                    else
                        app.gratingPeriod = unique(gP);
                        app.GratingPeriodEditField.Value = string(round(10^-3 *app.gratingPeriod,4));
                    end
                elseif app.checkboxGrating == "angle"
                    gA = vpa(solve([subs(phi2total_grating(L,period,ang),{L,period},{app.gratingL,app.gratingPeriod}) == 0],ang));
                    gA = real(gA(imag(gA) == 0));
                    if isempty(gA)
                        flagNoResult = true;
                    else
                        app.gratingAngle = unique(gA);
                        app.angleofincidenceEditField.Value = string(round(app.gratingAngle,4));
                    end
                end
                if ~flagNoResult
                    phi2control(omega0) = vpa(subs(phi2grating(w, L, period, ang),{w, L, period, ang},{omega0, app.gratingL(1),app.gratingPeriod(1),app.gratingAngle(1)}));
                    app.controltext = "Grating Pair, L = " + string(round(app.gratingL(1) *10^3,4)) + "mm, Period = " + string(round(app.gratingPeriod(1) *10^-3,4)) + "mm^{-1}" + ", \theta = "+ string(round(app.gratingAngle(1),4)) + "^{\circ}";
                    app.phi2_control = phi2control(omega0);
                    ControlComponentGraph(app)
                    TotalGraph(app)
                else
                    app.notelengthnumbergrating_2.Visible = "on";
                end
            end
        end

        % Value changed function: asfunctionofSwitch_7
        function asfunctionofSwitch_7ValueChanged(app, event)
            changeAsfunction(app,app.asfunctionofSwitch_7.Value)
            cla(app.UIAxesControlComponent);
            cla(app.UIAxesDispersionControlTotal);
            if app.asfunctionofSwitch_7.Value == "λ"
                app.axesIsw = false;
                app.UIAxesControlComponent.XLim = app.limitlamb;
                app.UIAxesDispersionControlTotal.XLim = app.limitlamb;
            else
                app.axesIsw = true;
                app.UIAxesControlComponent.XLim = app.limitw;
                app.UIAxesDispersionControlTotal.XLim = app.limitw;
            end
            dispersionControl(app);
        end

        % Value changed function: Col1DropDown
        function Col1DropDownValueChanged(app, event)
                if app.Col1DropDown.Value == "w values"
                    app.UnitsDropDownCol1.Items = {'MHz', 'GHz', 'THz', 'PHz'};
                else 
                    app.UnitsDropDownCol1.Items = {'micrometer','nm', 'pm', 'fm'};
                end
        end

        % Value changed function: WavelengthrangenmSliderSetUp
        function WavelengthrangenmSliderSetUpValueChanged(app, event)
            value = app.WavelengthrangenmSliderSetUp.Value;
            changeRange(app, value)
            c = 299792458;
            rangew = 2*pi*c ./ value *10^-6;
            app.limitlamb = sort(value *10^-3);
            app.limitw = sort(rangew);
            if app.Wavelength < value(1)*10^-9 || app.Wavelength > value(2)*10^-9
                app.Wavelength = interp1(value*10^-9, value*10^-9, app.Wavelength, 'nearest', 'extrap');
                app.omega0 = 6*pi/app.Wavelength *10^8;
                changeWavelength(app, app.Wavelength*10^9);
            end
            cla(app.UIAxesRefIndex);
            refractiveIndex(app)
        end

        % Value changed function: WavelengthrangenmSliderDispersion
        function WavelengthrangenmSliderDispersionValueChanged(app, event)
            value = app.WavelengthrangenmSliderDispersion.Value;
            changeRange(app, value)
            c = 299792458;
            rangew = 2*pi*c ./ value *10^-6;
            app.limitlamb = sort(value *10^-3);
            app.limitw = sort(rangew);
            if app.Wavelength < value(1)*10^-9 || app.Wavelength > value(2)*10^-9
                app.Wavelength = interp1(value*10^-9, value*10^-9, app.Wavelength, 'nearest', 'extrap');
                app.omega0 = 6*pi/app.Wavelength *10^8;
                changeWavelength(app, app.Wavelength*10^9);
            end
            cla(app.UIAxesDispersion);
            syms w omega0
            syms fphi(w,omega0) phi(w)
            phi(w) = app.fphic;
            app.PODTextArea.Value = strcat(string(double(subs(diff(phi, app.POD_order),w,app.omega0)*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']');
            fphi(w, omega0) = app.fdispersion;
            fphi(w) = subs(fphi, omega0, app.omega0);
            txt = "\lambda_0 =  ";
            txt = strcat(txt, int2str(app.Wavelength*10^9));
            txt = strcat(txt, "nm, order = ");
            txt = strcat(txt, int2str(app.Order));
            if app.axesIsw   
                app.UIAxesDispersion.XLim = app.limitw;
                fplot(app.UIAxesDispersion, abs(fphi(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            else 
                app.UIAxesDispersion.XLim = app.limitlamb;
                c = 299792458;
                syms lamb
                syms fphi_l(lamb)
                fphi_l(lamb) = subs(fphi(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesDispersion, abs(fphi_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesDispersion);
        end

        % Value changed function: WavelengthrangenmSliderControlComponent
        function WavelengthrangenmSliderControlComponentValueChanged(app, event)
            value = app.WavelengthrangenmSliderControlComponent.Value;
            changeRange(app, value)
            c = 299792458;
            rangew = 2*pi*c ./ value *10^-6;
            app.limitlamb = sort(value *10^-3);
            app.limitw = sort(rangew);
            if app.Wavelength < value(1)*10^-9 || app.Wavelength > value(2)*10^-9
                app.Wavelength = interp1(value*10^-9, value*10^-9, app.Wavelength, 'nearest', 'extrap');
                app.omega0 = 6*pi/app.Wavelength *10^8;
                changeWavelength(app, app.Wavelength*10^9);
            end
            changeAsfunction(app,app.asfunctionofSwitch_5.Value);
            cla(app.UIAxesPrismRI);
            syms w
            syms fref_index(w)
            fref_index(w) = app.fref_index_prism;
            txt = "";
            txt = strcat(txt, app.Prism_name);
            if app.axesIsw
                app.UIAxesPrismRI.XLim = app.limitw;
                fplot(app.UIAxesPrismRI, abs(fref_index(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);
            else
                syms lamb
                syms fref_index_l(lamb)
                fref_index_l(lamb) = subs(fref_index(w), w, 2*pi*c/lamb);
                fplot(app.UIAxesPrismRI, abs(fref_index_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);
            end
            legend(app.UIAxesPrismRI);
        end

        % Value changed function: DispersionParameterorderSpinnerTotal
        function DispersionParameterorderSpinnerTotalValueChanged(app, event)
            app.POD_order = app.DispersionParameterorderSpinnerTotal.Value;
            changePODorder(app, app.POD_order)
            syms w omega0
            syms phi(w) 
            phi(w) = app.fphi0TotalMaterials;
            phi_n = 0;
            c = 299792458;
            if app.isFile && app.isFileUploaded
                if app.Col1File == "omega" 
                    index_omega0 = interp1(app.Col1Data(1:end-2), 1:numel(app.Col1Data(1:end-2)), app.omega0, 'nearest', 'extrap');
                else
                    index_omega0 = interp1(2*pi*c./app.Col1Data(1:end-2), 1:numel(app.Col1Data(1:end-2)), app.omega0, 'nearest', 'extrap');
                end
                gdd0 = app.FileGdd(index_omega0);
                tod0 = app.FileTod(index_omega0);
                if app.POD_order >= 3
                    if app.POD_order > 3
                        changePODorder(app, 3)
                    end
                    app.POD_order = 3;
                     phi_n = tod0 + subs(diff(phi, app.POD_order),w,app.omega0);
                elseif app.POD_order <= 2
                    if app.POD_order < 2
                        changePODorder(app, 2)
                    end
                    app.POD_order = 2;
                    phi_n = gdd0 + subs(diff(phi, app.POD_order),w,app.omega0);
                end
            else
                syms GDD(omega0) 
                if app.isFile && ~app.isFileUploaded
                    GDD(omega0) = 0;
                else
                    GDD(omega0) = app.phi2_control;
                end
                phi_n = double(subs(diff(phi, app.POD_order),w,app.omega0)) + double(subs(diff(GDD, app.POD_order-2),omega0,app.omega0));
            end
            app.PODTextAreaTotal.Value = strcat(string(double(phi_n*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']');
        end

        % Value changed function: orderSpinner_3
        function orderSpinner_3ValueChanged(app, event)
            changeOrder(app,app.orderSpinner_3.Value);
            app.Order = app.orderSpinner_3.Value;
            TotalGraph(app)
        end

        % Value changed function: WavelengthrangenmSliderControl
        function WavelengthrangenmSliderControlValueChanged(app, event)
            if ~app.isFile || ~app.isFileUploaded
                value = app.WavelengthrangenmSliderControl.Value;
                changeRange(app, value)
                c = 299792458;
                rangew = 2*pi*c ./ value *10^-6;
                app.limitlamb = sort(value *10^-3);
                app.limitw = sort(rangew);
                if app.Wavelength < value(1)*10^-9 || app.Wavelength > value(2)*10^-9
                    app.Wavelength = interp1(value*10^-9, value*10^-9, app.Wavelength, 'nearest', 'extrap');
                    app.omega0 = 6*pi/app.Wavelength *10^8;
                    changeWavelength(app, app.Wavelength*10^9);
                end
                 c = 299792458;
                syms w omega0
                syms fphi(w,omega0) phi(w) 
                fphi(w,omega0) = app.fphiTotalMaterials;
                phi(w) = app.fphi0TotalMaterials;
                syms GDD(omega0) TOD(omega0)
                if app.isFile && ~app.isFileUploaded
                    GDD(omega0) = 0;
                    TOD(omega0) = 0;
                else
                    GDD(omega0) = app.phi2_control;
                end
                phi_n = double(subs(diff(phi, app.POD_order),w,app.omega0)) + double(subs(diff(GDD, app.POD_order-2),omega0,app.omega0));
                app.PODTextAreaTotal.Value = strcat(string(double(phi_n*10^(15*app.POD_order))), '[fs^', int2str(app.POD_order),']');
                for n = 2 : app.Order
                    fphi(w,omega0) = fphi(w,omega0) + (w-omega0)^n /factorial(n) * diff(GDD(omega0), n-2,omega0);
                end
                fphi(w) = subs(fphi(w,omega0), omega0, app.omega0);
                txt = "\lambda_0 =  ";
                txt = strcat(txt, int2str(app.Wavelength*10^9));
                txt = strcat(txt, "nm, order = ");
                txt = strcat(txt, int2str(app.Order));
                if app.axesIsw   
                    xlabel(app.UIAxesDispersionControlTotal, '\omega[PHz]')
                    ylabel(app.UIAxesDispersionControlTotal, '\phi(\omega)')
                    app.UIAxesDispersionControlTotal.XLim = app.limitw;
                    fplot(app.UIAxesDispersionControlTotal, abs(fphi(w*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', txt);

                    xlabel(app.UIAxesControlComponent, '\omega[PHz]')
                    app.UIAxesControlComponent.XLim = app.limitw;
                    if app.controlgraphtype == "GDD"
                        ylabel(app.UIAxesControlComponent, 'GDD(\omega)[fs^2]')
                        fplot(app.UIAxesControlComponent, (10^30)*real(GDD(omega0*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', app.controltext);
                    else
                        TOD(omega0) = diff(GDD,1,omega0);
                        ylabel(app.UIAxesControlComponent, 'TOD(\omega)[fs^3]')
                        fplot(app.UIAxesControlComponent, (10^45)*real(TOD(omega0*10^15)), app.limitw, 'LineWidth', 1.5,'DisplayName', app.controltext);
                    end
                else 
                    app.UIAxesDispersionControlTotal.XLim = app.limitlamb;
                    xlabel(app.UIAxesDispersionControlTotal, '\lambda[µm]')
                    ylabel(app.UIAxesDispersionControlTotal, '\phi(\lambda)')
                    syms lamb
                    syms fphi_l(lamb)
                    fphi_l(lamb) = subs(fphi(w), w, 2*pi*c/lamb);
                    fplot(app.UIAxesDispersionControlTotal, abs(fphi_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', txt);

                    app.UIAxesControlComponent.XLim = app.limitlamb;
                    xlabel(app.UIAxesControlComponent, '\lambda[µm]')
                    syms lamb
                    syms GDD_l(lamb) TOD_l(lamb)
                    if app.controlgraphtype == "GDD"
                        GDD_l(lamb) = subs(GDD(omega0), omega0, 2*pi*c/lamb);
                        ylabel(app.UIAxesControlComponent, 'GDD(\lambda)[fs^2]')
                        fplot(app.UIAxesControlComponent, (10^30)*real(GDD_l(lamb*10^-6)), app.limitlamb,'LineWidth', 1.5,'DisplayName', app.controltext);
                    else
                        TOD(omega0) = diff(GDD,1,omega0);
                        TOD_l(lamb) = subs(TOD(omega0), omega0, 2*pi*c/lamb);
                        ylabel(app.UIAxesControlComponent, 'TOD(\lambda)[fs^3]')
                        fplot(app.UIAxesControlComponent, (10^45)*real(TOD_l(lamb*10^-6)), app.limitlamb, 'LineWidth', 1.5,'DisplayName', app.controltext);
                    end
                end
            end
            legend(app.UIAxesControlComponent);
            legend(app.UIAxesDispersionControlTotal);
            
        end

        % Button pushed function: savegraphButton_5
        function savegraphButton_5Pushed(app, event)
             saveGraph(app,app.UIAxesControlComponent);
             saveGraph(app,app.UIAxesDispersionControlTotal);
        end

        % Button pushed function: resetgraphButton_5
        function resetgraphButton_5Pushed(app, event)
            cla(app.UIAxesControlComponent);
            cla(app.UIAxesDispersionControlTotal);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            syms omega0 H R 
            RR = 0;
            HH = 0;
            if value == "TOD = 0"
                app.prismpairOrder = "TOD";
                syms H3(omega0) R3(omega0)
                R3(omega0) = app.fRthird;
                H3(omega0) = app.fRthird;
                RR = subs(R3(omega0),omega0,app.omega0);
                HH = subs(H3(omega0),omega0,app.omega0);
            else
                app.prismpairOrder = "FOD";
                syms H4(omega0) R4(omega0)
                R4(omega0) = app.fRfourth;
                H4(omega0) = app.fRfourth;
                RR = subs(R4(omega0),omega0,app.omega0);
                HH = subs(H4(omega0),omega0,app.omega0);
            end
            app.RTextArea.Value = string(vpa(10^3 *RR,5));
            app.HTextArea.Value = string(vpa(10^3 *HH,5));
            %if (app.H == 0 && app.R == 0)||~app.flagValueChosen
            app.H = HH;
            app.R = RR;
            if app.R >= 0
                app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlPositive.png');
            else
                app.Image.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'icons', 'dispersionControlNegative.png');  
            end
            app.controltext = "Prism Pair, R = " + string(round(app.R*10^3,4)) + "mm, H = " + string(round(app.H*10^3,4)) + "mm";
            syms phi2prism(omega0, R, H) phi2control(omega0)
            phi2prism(omega0, R, H) = app.phi2_prism
            phi2control(omega0) = vpa(subs(phi2prism(omega0, R, H),{R, H},{app.R, app.H}));
            app.phi2_control = phi2control(omega0);
            ControlComponentGraph(app)
            TotalGraph(app)
            %end
        end

        % Value changed function: DistanceLCheckBox
        function DistanceLCheckBoxValueChanged(app, event)
            value = app.DistanceLCheckBox.Value;
            if value == true
                app.checkboxGrating = "Distance";
                app.angleofincidenceCheckBox.Value = false;
                app.GratingdensityCheckBox.Value = false;
            else
                app.checkboxGrating = "Nall";
            end
        end

        % Value changed function: angleofincidenceCheckBox
        function angleofincidenceCheckBoxValueChanged(app, event)
            value = app.angleofincidenceCheckBox.Value;
            if value == true
                app.checkboxGrating = "angle";
                app.DistanceLCheckBox.Value = false;
                app.GratingdensityCheckBox.Value = false;
            else
                app.checkboxGrating = "Nall";
            end
        end

        % Value changed function: GratingdensityCheckBox
        function GratingdensityCheckBoxValueChanged(app, event)
            value = app.GratingdensityCheckBox.Value;
            if value == true
                app.checkboxGrating = "Period";
                app.DistanceLCheckBox.Value = false;
                app.angleofincidenceCheckBox.Value = false;
            else
                app.checkboxGrating = "Nall";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Set up';
            app.SetupTab.ButtonDownFcn = createCallbackFcn(app, @SetupTabButtonDown, true);
            app.UIAxesRefIndex = uiaxes(app.SetupTab);
            xlabel(app.UIAxesRefIndex, '\omega[PHz]')
            ylabel(app.UIAxesRefIndex, 'n(\omega)')
            zlabel(app.UIAxesRefIndex, 'Z')
            app.UIAxesRefIndex.XAxisLocation = 'origin';
            app.UIAxesRefIndex.YAxisLocation = 'origin';
            app.UITablematerials = uitable(app.SetupTab);
            app.UITablematerials.ColumnName = {'name'; 'length(mm)'; 'Refractive index'};
            app.UITablematerials.RowName = {};
            app.UITablematerials.SelectionType = 'row';
            app.UITablematerials.CellSelectionCallback = createCallbackFcn(app, @UITablematerialsCellSelection, true);
            app.UITablematerials.Multiselect = 'off';
            app.DeletematerialButton = uibutton(app.SetupTab, 'push');
            app.DeletematerialButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeletematerialButton.Icon = fullfile(pathToMLAPP, 'icons', 'delete.png');
            app.DeletematerialButton.Text = 'Delete material';
            app.rownumberEditField_Label = uilabel(app.SetupTab);
            app.rownumberEditField_Label.Text = 'row number';
            app.rownumberEditField = uieditfield(app.SetupTab, 'text');
            app.rownumberEditField.InputType = 'digits';
            app.rownumberEditField.Placeholder = '0';
            app.CalculateDispersionButton = uibutton(app.SetupTab, 'push');
            app.CalculateDispersionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDispersionButtonPushed, true);
            app.CalculateDispersionButton.Icon = fullfile(pathToMLAPP, 'icons', 'dispersion.png');
            app.CalculateDispersionButton.Text = 'Calculate Dispersion';
            app.Panel = uipanel(app.SetupTab);
            app.addelementButton = uibutton(app.Panel, 'state');
            app.addelementButton.ValueChangedFcn = createCallbackFcn(app, @addelementButtonValueChanged, true);
            app.addelementButton.Icon = fullfile(pathToMLAPP, 'icons', 'add.png');
            app.addelementButton.Text = 'add element';
            app.AddOpticalElementsLabel = uilabel(app.Panel);
            app.AddOpticalElementsLabel.Text = 'Add Optical Elements';
            app.materialSwitch = uiswitch(app.Panel, 'slider');
            app.materialSwitch.Items = {'From Database', 'New Material'};
            app.materialSwitch.ValueChangedFcn = createCallbackFcn(app, @materialSwitchValueChanged, true);
            app.materialSwitch.Value = 'From Database';
            app.notematerial = uilabel(app.Panel);
            app.notematerial.Visible = 'off';
            app.notematerial.Text = 'Please select the component material';
            app.notelength = uilabel(app.Panel);
            app.notelength.Visible = 'off';
            app.notelength.Text = 'Please enter the component length';
            app.notelengthnumber = uilabel(app.Panel);
            app.notelengthnumber.Visible = 'off';
            app.notelengthnumber.Text = 'the length can conatin only numbers';
            app.groupDropDownLabel = uilabel(app.Panel);
            app.groupDropDownLabel.Text = 'group';
            app.groupDropDown = uidropdown(app.Panel);
            app.groupDropDown.Items = {};
            app.groupDropDown.Editable = 'on';
            app.groupDropDown.ValueChangedFcn = createCallbackFcn(app, @groupDropDownValueChanged, true);
            app.groupDropDown.Tag = '1';
            app.groupDropDown.Value = {};
            app.subgroupDropDownLabel = uilabel(app.Panel);
            app.subgroupDropDownLabel.Text = 'sub group';
            app.subgroupDropDown = uidropdown(app.Panel);
            app.subgroupDropDown.Items = {};
            app.subgroupDropDown.Editable = 'on';
            app.subgroupDropDown.ValueChangedFcn = createCallbackFcn(app, @subgroupDropDownValueChanged, true);
            app.subgroupDropDown.Value = {};
            app.nameEditFieldLabel = uilabel(app.Panel);
            app.nameEditFieldLabel.Visible = 'off';
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.Panel, 'text');
            app.nameEditField.Visible = 'off';
            app.nameEditField.Placeholder = 'optional';
            app.lengthEditFieldLabel = uilabel(app.Panel);
            app.lengthEditFieldLabel.Text = 'length';
            app.lengthEditField = uieditfield(app.Panel, 'text');
            app.lengthEditField.Placeholder = 'in millimeter(mm) unit';
            app.RefractiveindexEditFieldLabel = uilabel(app.Panel);
            app.RefractiveindexEditFieldLabel.Visible = 'off';
            app.RefractiveindexEditFieldLabel.Text = 'Refractive index';
            app.RefractiveindexEditField = uieditfield(app.Panel, 'text');
            app.RefractiveindexEditField.Visible = 'off';
            app.RefractiveindexEditField.Placeholder = 'as function of frequency(w) or wavelength(lamb)';
            app.materialnameDropDownLabel = uilabel(app.Panel);
            app.materialnameDropDownLabel.Text = 'material name';
            app.materialnameDropDown = uidropdown(app.Panel);
            app.materialnameDropDown.Items = {};
            app.materialnameDropDown.Editable = 'on';
            app.materialnameDropDown.ValueChangedFcn = createCallbackFcn(app, @materialnameDropDownValueChanged, true);
            app.materialnameDropDown.Value = {};
            app.noterefindex = uilabel(app.Panel);
            app.noterefindex.Visible = 'off';
            app.noterefindex.Text = 'Please enter the refractive index of the component';
            app.noterefindexcontain = uilabel(app.Panel);
            app.noterefindexcontain.Visible = 'off';
            app.noterefindexcontain.Text = 'enter the refractive index only as function of w or lamb';
            app.resetgraphButton = uibutton(app.SetupTab, 'push');
            app.resetgraphButton.ButtonPushedFcn = createCallbackFcn(app, @resetgraphButtonPushed, true);
            app.resetgraphButton.Icon = fullfile(pathToMLAPP, 'icons', 'undo (1).png');
            app.resetgraphButton.Text = 'reset graph';
            app.savegraphButton = uibutton(app.SetupTab, 'push');
            app.savegraphButton.ButtonPushedFcn = createCallbackFcn(app, @savegraphButtonPushed, true);
            app.savegraphButton.Icon = fullfile(pathToMLAPP, 'icons', 'image-.png');
            app.savegraphButton.Text = 'save graph ';
            app.asfunctionofLabel_2 = uilabel(app.SetupTab);
            app.asfunctionofLabel_2.Text = 'as function of:';
            app.asfunctionofSwitch_3 = uiswitch(app.SetupTab, 'toggle');
            app.asfunctionofSwitch_3.Items = {'ω', 'λ'};
            app.asfunctionofSwitch_3.ValueChangedFcn = createCallbackFcn(app, @asfunctionofSwitch_3ValueChanged, true);
            app.asfunctionofSwitch_3.Value = 'ω';
            app.DropDown = uidropdown(app.SetupTab);
            app.DropDown.Items = {'Refractive Index', 'GDD', 'TOD'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Refractive Index';
            app.WavelengthrangenmSliderLabel = uilabel(app.SetupTab);
            app.WavelengthrangenmSliderLabel.Text = 'Wavelength range(nm):';
            app.WavelengthrangenmSliderSetUp = uislider(app.SetupTab, 'range');
            app.WavelengthrangenmSliderSetUp.Limits = [0 2000];
            app.WavelengthrangenmSliderSetUp.ValueChangedFcn = createCallbackFcn(app, @WavelengthrangenmSliderSetUpValueChanged, true);
            app.WavelengthrangenmSliderSetUp.Value = [400 1600];
            app.DispersionFunctionTab = uitab(app.TabGroup);
            app.DispersionFunctionTab.Title = 'Dispersion Function';
            app.DispersionFunctionTab.ButtonDownFcn = createCallbackFcn(app, @DispersionFunctionTabButtonDown, true);
            app.UIAxesDispersion = uiaxes(app.DispersionFunctionTab);
            title(app.UIAxesDispersion, '\color{white}Spectral Phase')
            xlabel(app.UIAxesDispersion, '\omega[PHz]')
            ylabel(app.UIAxesDispersion, '\phi(\omega)')
            zlabel(app.UIAxesDispersion, 'Z')
            app.UIAxesDispersion.XAxisLocation = 'origin';
            app.UIAxesDispersion.YAxisLocation = 'origin';
            app.wavelengthinfreespaceLabel = uilabel(app.DispersionFunctionTab);
            app.wavelengthinfreespaceLabel.Text = 'wavelength in  free space(nm)';
            app.wavelengthinfreespacenmSlider = uislider(app.DispersionFunctionTab);
            app.wavelengthinfreespacenmSlider.Limits = [0 2000];
            app.wavelengthinfreespacenmSlider.ValueChangedFcn = createCallbackFcn(app, @wavelengthinfreespacenmSliderValueChanged, true);
            app.wavelengthinfreespacenmSlider.Value = 623;
            app.orderSpinnerLabel = uilabel(app.DispersionFunctionTab);
            app.orderSpinnerLabel.Text = 'order';
            app.orderSpinner = uispinner(app.DispersionFunctionTab);
            app.orderSpinner.Limits = [2 6];
            app.orderSpinner.ValueChangedFcn = createCallbackFcn(app, @orderSpinnerValueChanged, true);
            app.orderSpinner.Value = 3;
            app.UITablelistmaterials = uitable(app.DispersionFunctionTab);
            app.UITablelistmaterials.ColumnName = {'Choose'; 'Name'};
            app.UITablelistmaterials.RowName = {};
            app.UITablelistmaterials.ColumnEditable = [true false];
            app.UITablelistmaterials.DisplayDataChangedFcn = createCallbackFcn(app, @UITablelistmaterialsDisplayDataChanged, true);
            app.UITablelistmaterials.SelectionChangedFcn = createCallbackFcn(app, @UITablelistmaterialsSelectionChanged, true);
            app.DispersionControlButton = uibutton(app.DispersionFunctionTab, 'push');
            app.DispersionControlButton.ButtonPushedFcn = createCallbackFcn(app, @DispersionControlButtonPushed, true);
            app.DispersionControlButton.Icon = fullfile(pathToMLAPP, 'icons', 'prism2.png');
            app.DispersionControlButton.Text = 'Dispersion Control';
            app.asfunctionofLabel = uilabel(app.DispersionFunctionTab);
            app.asfunctionofLabel.Text = 'as function of:';
            app.asfunctionofSwitch_4 = uiswitch(app.DispersionFunctionTab, 'toggle');
            app.asfunctionofSwitch_4.Items = {'ω', 'λ'};
            app.asfunctionofSwitch_4.ValueChangedFcn = createCallbackFcn(app, @asfunctionofSwitch_4ValueChanged, true);
            app.asfunctionofSwitch_4.Value = 'ω';
            app.savegraphButton_2 = uibutton(app.DispersionFunctionTab, 'push');
            app.savegraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @savegraphButton_2Pushed, true);
            app.savegraphButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'image-.png');
            app.savegraphButton_2.Text = 'save graph ';
            app.resetgraphButton_2 = uibutton(app.DispersionFunctionTab, 'push');
            app.resetgraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetgraphButton_2Pushed, true);
            app.resetgraphButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'undo (1).png');
            app.resetgraphButton_2.Text = 'reset graph';
            app.PODTextArea = uitextarea(app.DispersionFunctionTab);
            app.DispersionParameterorderSpinnerLabel = uilabel(app.DispersionFunctionTab);
            app.DispersionParameterorderSpinnerLabel.Text = 'Dispersion Parameter order';
            app.DispersionParameterorderSpinner = uispinner(app.DispersionFunctionTab);
            app.DispersionParameterorderSpinner.Limits = [0 10];
            app.DispersionParameterorderSpinner.RoundFractionalValues = 'on';
            app.DispersionParameterorderSpinner.ValueChangedFcn = createCallbackFcn(app, @DispersionParameterorderSpinnerValueChanged, true);
            app.DispersionParameterorderSpinner.Value = 2;
            app.WavelengthrangenmSliderLabel_2 = uilabel(app.DispersionFunctionTab);
            app.WavelengthrangenmSliderLabel_2.Text = 'Wavelength range(nm):';
            app.WavelengthrangenmSliderDispersion = uislider(app.DispersionFunctionTab, 'range');
            app.WavelengthrangenmSliderDispersion.Limits = [0 2000];
            app.WavelengthrangenmSliderDispersion.ValueChangedFcn = createCallbackFcn(app, @WavelengthrangenmSliderDispersionValueChanged, true);
            app.WavelengthrangenmSliderDispersion.Value = [400 1600];
            app.DispersionControlComponentTab = uitab(app.TabGroup);
            app.DispersionControlComponentTab.Title = 'Dispersion Control Component';
            app.DispersionControlComponentTab.ButtonDownFcn = createCallbackFcn(app, @DispersionControlComponentTabButtonDown, true);
            app.UIAxesPrismRI = uiaxes(app.DispersionControlComponentTab);
            title(app.UIAxesPrismRI, '\color{white} Refractive Index')
            xlabel(app.UIAxesPrismRI, '\omega')
            ylabel(app.UIAxesPrismRI, 'n(\omega)')
            zlabel(app.UIAxesPrismRI, 'Z')
            app.UIAxesPrismRI.XAxisLocation = 'origin';
            app.UIAxesPrismRI.YAxisLocation = 'origin';
            app.addprismmaterialButton = uibutton(app.DispersionControlComponentTab, 'state');
            app.addprismmaterialButton.ValueChangedFcn = createCallbackFcn(app, @addprismmaterialButtonValueChanged, true);
            app.addprismmaterialButton.Text = 'choose dispersion control material';
            app.materialPrismSwitch = uiswitch(app.DispersionControlComponentTab, 'slider');
            app.materialPrismSwitch.Items = {'From Database', 'New Material'};
            app.materialPrismSwitch.ValueChangedFcn = createCallbackFcn(app, @materialPrismSwitchValueChanged, true);
            app.materialPrismSwitch.Value = 'From Database';
            app.CalculateDispersionControlButton = uibutton(app.DispersionControlComponentTab, 'push');
            app.CalculateDispersionControlButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDispersionControlButtonPushed, true);
            app.CalculateDispersionControlButton.Text = 'Calculate Dispersion Control';
            app.resetgraphButton_3 = uibutton(app.DispersionControlComponentTab, 'push');
            app.resetgraphButton_3.ButtonPushedFcn = createCallbackFcn(app, @resetgraphButton_3Pushed, true);
            app.resetgraphButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'undo (1).png');
            app.resetgraphButton_3.Text = 'reset graph';
            app.savegraphButton_3 = uibutton(app.DispersionControlComponentTab, 'push');
            app.savegraphButton_3.ButtonPushedFcn = createCallbackFcn(app, @savegraphButton_3Pushed, true);
            app.savegraphButton_3.Icon = fullfile(pathToMLAPP, 'icons', 'image-.png');
            app.savegraphButton_3.Text = 'save graph ';
            app.asfunctionofSwitch_5Label = uilabel(app.DispersionControlComponentTab);
            app.asfunctionofSwitch_5Label.Text = 'as function of';
            app.asfunctionofSwitch_5 = uiswitch(app.DispersionControlComponentTab, 'toggle');
            app.asfunctionofSwitch_5.Items = {'ω', 'λ'};
            app.asfunctionofSwitch_5.ValueChangedFcn = createCallbackFcn(app, @asfunctionofSwitch_5ValueChanged, true);
            app.asfunctionofSwitch_5.Value = 'ω';
            app.DispersionControlComponentLabel = uilabel(app.DispersionControlComponentTab);
            app.DispersionControlComponentLabel.Text = 'Dispersion Control Component';
            app.noterefindexprism = uilabel(app.DispersionControlComponentTab);
            app.noterefindexprism.Visible = 'off';
            app.noterefindexprism.Text = 'Please enter the refractive index of the component';
            app.nameprismEditField_Label = uilabel(app.DispersionControlComponentTab);
            app.nameprismEditField_Label.Visible = 'off';
            app.nameprismEditField_Label.Text = 'name';
            app.nameprismEditField = uieditfield(app.DispersionControlComponentTab, 'text');
            app.nameprismEditField.Visible = 'off';
            app.nameprismEditField.Placeholder = 'optional';
            app.RefractiveindexprismEditField_Label = uilabel(app.DispersionControlComponentTab);
            app.RefractiveindexprismEditField_Label.Visible = 'off';
            app.RefractiveindexprismEditField_Label.Text = 'Refractive index';
            app.RefractiveindexprismEditField = uieditfield(app.DispersionControlComponentTab, 'text');
            app.RefractiveindexprismEditField.Visible = 'off';
            app.RefractiveindexprismEditField.Placeholder = 'as function of frequency(w) or wavelength(lamb)';
            app.noterefindexcontainprism = uilabel(app.DispersionControlComponentTab);
            app.noterefindexcontainprism.Visible = 'off';
            app.noterefindexcontainprism.Text = 'enter the refractive index only as function of w or lamb';
            app.notematerialprism = uilabel(app.DispersionControlComponentTab);
            app.notematerialprism.Visible = 'off';
            app.notematerialprism.Text = 'Please select the prism material';
            app.groupprismDropDown_Label = uilabel(app.DispersionControlComponentTab);
            app.groupprismDropDown_Label.Text = 'group';
            app.groupprismDropDown = uidropdown(app.DispersionControlComponentTab);
            app.groupprismDropDown.Items = {};
            app.groupprismDropDown.Editable = 'on';
            app.groupprismDropDown.ValueChangedFcn = createCallbackFcn(app, @groupprismDropDownValueChanged, true);
            app.groupprismDropDown.Tag = '1';
            app.groupprismDropDown.Value = {};
            app.subgroupprismDropDown_Label = uilabel(app.DispersionControlComponentTab);
            app.subgroupprismDropDown_Label.Text = 'sub group';
            app.subgroupprismDropDown = uidropdown(app.DispersionControlComponentTab);
            app.subgroupprismDropDown.Items = {};
            app.subgroupprismDropDown.Editable = 'on';
            app.subgroupprismDropDown.ValueChangedFcn = createCallbackFcn(app, @subgroupprismDropDownValueChanged, true);
            app.subgroupprismDropDown.Value = {};
            app.materialnameprismDropDown_Label = uilabel(app.DispersionControlComponentTab);
            app.materialnameprismDropDown_Label.Text = 'material name';
            app.materialnameprismDropDown = uidropdown(app.DispersionControlComponentTab);
            app.materialnameprismDropDown.Items = {};
            app.materialnameprismDropDown.Editable = 'on';
            app.materialnameprismDropDown.ValueChangedFcn = createCallbackFcn(app, @materialnameprismDropDownValueChanged, true);
            app.materialnameprismDropDown.Value = {};
            app.WavelengthrangenmSliderLabel_3 = uilabel(app.DispersionControlComponentTab);
            app.WavelengthrangenmSliderLabel_3.Text = 'Wavelength range(nm):';
            app.WavelengthrangenmSliderControlComponent = uislider(app.DispersionControlComponentTab, 'range');
            app.WavelengthrangenmSliderControlComponent.Limits = [0 2000];
            app.WavelengthrangenmSliderControlComponent.ValueChangedFcn = createCallbackFcn(app, @WavelengthrangenmSliderControlComponentValueChanged, true);
            app.WavelengthrangenmSliderControlComponent.Value = [400 1600];
            app.DispersionControlTab = uitab(app.TabGroup);
            app.DispersionControlTab.Title = 'Dispersion Control';
            app.DispersionControlTab.ButtonDownFcn = createCallbackFcn(app, @DispersionControlTabButtonDown, true);
            app.UIAxesDispersionControlTotal = uiaxes(app.DispersionControlTab);
            title(app.UIAxesDispersionControlTotal, '\color{white}Spectral Phase')
            xlabel(app.UIAxesDispersionControlTotal, '\omega[PHz]')
            ylabel(app.UIAxesDispersionControlTotal, '\phi(\omega)')
            zlabel(app.UIAxesDispersionControlTotal, 'Z')
            app.UIAxesDispersionControlTotal.XAxisLocation = 'origin';
            app.UIAxesDispersionControlTotal.YAxisLocation = 'origin';
            app.UIAxesControlComponent = uiaxes(app.DispersionControlTab);
            xlabel(app.UIAxesControlComponent, '\omega[PHz]')
            ylabel(app.UIAxesControlComponent, 'GDD(\omega)')
            zlabel(app.UIAxesControlComponent, 'Z')
            app.UIAxesControlComponent.XAxisLocation = 'origin';
            app.UIAxesControlComponent.YAxisLocation = 'origin';
            app.wavelengthinfreespacenmSlider_3Label = uilabel(app.DispersionControlTab);
            app.wavelengthinfreespacenmSlider_3Label.Text = 'wavelength in  free space(nm)';
            app.wavelengthinfreespacenmSliderControl = uislider(app.DispersionControlTab);
            app.wavelengthinfreespacenmSliderControl.Limits = [0 2000];
            app.wavelengthinfreespacenmSliderControl.ValueChangedFcn = createCallbackFcn(app, @wavelengthinfreespacenmSliderControlValueChanged, true);
            app.wavelengthinfreespacenmSliderControl.Value = 623;
            app.DispersionControlByButtonGroup = uibuttongroup(app.DispersionControlTab);
            app.DispersionControlByButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DispersionControlByButtonGroupSelectionChanged, true);
            app.DispersionControlByButtonGroup.Title = 'Dispersion Control By:';
            app.PrismPairButton = uitogglebutton(app.DispersionControlByButtonGroup);
            app.PrismPairButton.Text = 'Prism Pair';
            app.WindowButton = uitogglebutton(app.DispersionControlByButtonGroup);
            app.WindowButton.Text = 'Window';
            app.DispersiveMirrorButton = uitogglebutton(app.DispersionControlByButtonGroup);
            app.DispersiveMirrorButton.Text = 'Dispersive Mirror';
            app.DispersiveMirrorButton.Value = true;
            app.GratingpairButton = uitogglebutton(app.DispersionControlByButtonGroup);
            app.GratingpairButton.Text = 'Grating pair';
            app.Panel_prism = uipanel(app.DispersionControlTab);
            app.Panel_prism.Visible = 'off';
            app.Panel_prism.ButtonDownFcn = createCallbackFcn(app, @Panel_prismButtonDown, true);
            app.GDD0atLabel = uilabel(app.Panel_prism);
            app.GDD0atLabel.Text = 'GDD = 0 at: ';
            app.RHTextAreaLabel = uilabel(app.Panel_prism);
            app.RHTextAreaLabel.Text = 'R(H)';
            app.RHTextArea = uitextarea(app.Panel_prism);
            app.RHTextArea.Editable = 'off';
            app.RTextAreaLabel = uilabel(app.Panel_prism);
            app.RTextAreaLabel.Text = 'R';
            app.RTextArea = uitextarea(app.Panel_prism);
            app.RTextArea.Editable = 'off';
            app.HTextAreaLabel = uilabel(app.Panel_prism);
            app.HTextAreaLabel.Text = 'H';
            app.HTextArea = uitextarea(app.Panel_prism);
            app.HTextArea.Editable = 'off';
            app.Image = uiimage(app.Panel_prism);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'dispersionControlPositive.png');
            app.DistanceLEditField_2Label_2 = uilabel(app.Panel_prism);
            app.DistanceLEditField_2Label_2.Text = 'R';
            app.REditField = uieditfield(app.Panel_prism, 'text');
            app.REditField.Placeholder = 'mm';
            app.HEditFieldLabel = uilabel(app.Panel_prism);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.Panel_prism, 'text');
            app.HEditField.Placeholder = 'mm';
            app.CalculateButtonPrism = uibutton(app.Panel_prism, 'push');
            app.CalculateButtonPrism.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPrismPushed, true);
            app.CalculateButtonPrism.Text = 'Calculate';
            app.Unit2 = uitextarea(app.Panel_prism);
            app.Unit2.Editable = 'off';
            app.Unit2.Value = {'mm'};
            app.Unit3 = uitextarea(app.Panel_prism);
            app.Unit3.Editable = 'off';
            app.Unit3.Value = {'mm'};
            app.Unit = uitextarea(app.Panel_prism);
            app.Unit.Editable = 'off';
            app.Unit.Value = {'mm'};
            app.notelengthnumberprism = uilabel(app.Panel_prism);
            app.notelengthnumberprism.Visible = 'off';
            app.notelengthnumberprism.Text = 'the values should conatin only numbers';
            app.HaccumulativepathlengthinglassLabel = uilabel(app.Panel_prism);
            app.HaccumulativepathlengthinglassLabel.Text = 'H: accumulative path length in glass';
            app.RPrismseparationLabel = uilabel(app.Panel_prism);
            app.RPrismseparationLabel.Text = 'R: Prism separation';
            app.Switch = uiswitch(app.Panel_prism, 'slider');
            app.Switch.Items = {'TOD = 0', 'FOD = 0'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'TOD = 0';
            app.Panel_window = uipanel(app.DispersionControlTab);
            app.Panel_window.Visible = 'off';
            app.GDD0atLabel_2 = uilabel(app.Panel_window);
            app.GDD0atLabel_2.Text = 'GDD = 0 at: ';
            app.LTextAreaLabel = uilabel(app.Panel_window);
            app.LTextAreaLabel.Text = 'L';
            app.LTextArea = uitextarea(app.Panel_window);
            app.LTextArea.Editable = 'off';
            app.Unit2_2 = uitextarea(app.Panel_window);
            app.Unit2_2.Editable = 'off';
            app.Unit2_2.Value = {'mm'};
            app.CalculateButtonWindow = uibutton(app.Panel_window, 'push');
            app.CalculateButtonWindow.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonWindowPushed, true);
            app.CalculateButtonWindow.Text = 'Calculate';
            app.notelengthnumberwindow = uilabel(app.Panel_window);
            app.notelengthnumberwindow.Visible = 'off';
            app.notelengthnumberwindow.Text = 'the value should conatin only numbers';
            app.LEditFieldLabel = uilabel(app.Panel_window);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Panel_window, 'text');
            app.LEditField.Placeholder = 'mm';
            app.Panel_mirror = uipanel(app.DispersionControlTab);
            app.uploadExelFileButton = uibutton(app.Panel_mirror, 'push');
            app.uploadExelFileButton.ButtonPushedFcn = createCallbackFcn(app, @uploadExelFileButtonPushed, true);
            app.uploadExelFileButton.Text = 'upload Exel File';
            app.Col1DropDownLabel = uilabel(app.Panel_mirror);
            app.Col1DropDownLabel.Text = 'Col 1';
            app.Col1DropDown = uidropdown(app.Panel_mirror);
            app.Col1DropDown.Items = {'w values', 'λ values'};
            app.Col1DropDown.ValueChangedFcn = createCallbackFcn(app, @Col1DropDownValueChanged, true);
            app.Col1DropDown.Value = 'w values';
            app.Col2DropDownLabel = uilabel(app.Panel_mirror);
            app.Col2DropDownLabel.Text = 'Col 2';
            app.Col2DropDown = uidropdown(app.Panel_mirror);
            app.Col2DropDown.Items = {'Group Delay', 'Group Delay Dispersion'};
            app.Col2DropDown.Value = 'Group Delay';
            app.UnitsDropDownLabel = uilabel(app.Panel_mirror);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDownCol1 = uidropdown(app.Panel_mirror);
            app.UnitsDropDownCol1.Items = {'MHz', 'GHz', 'THz', 'PHz'};
            app.UnitsDropDownCol1.Value = 'MHz';
            app.UnitsDropDown_2Label = uilabel(app.Panel_mirror);
            app.UnitsDropDown_2Label.Text = 'Units';
            app.UnitsDropDownCol2 = uidropdown(app.Panel_mirror);
            app.UnitsDropDownCol2.Items = {'ns', 'ps', 'fs'};
            app.UnitsDropDownCol2.Value = 'fs';
            app.Panel_graiting = uipanel(app.DispersionControlTab);
            app.Panel_graiting.Visible = 'off';
            app.GratingPeriodEditField = uieditfield(app.Panel_graiting, 'text');
            app.GratingPeriodEditField.Placeholder = '/mm';
            app.DistanceLEditField = uieditfield(app.Panel_graiting, 'text');
            app.DistanceLEditField.Placeholder = 'mm';
            app.CalculateButtonGrating = uibutton(app.Panel_graiting, 'state');
            app.CalculateButtonGrating.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonGratingValueChanged, true);
            app.CalculateButtonGrating.Text = 'Calculate';
            app.angleofincidenceEditField = uieditfield(app.Panel_graiting, 'text');
            app.angleofincidenceEditField.Placeholder = 'deg';
            app.Image2 = uiimage(app.Panel_graiting);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icons', 'ControlGratingPair.png');
            app.notelengthnumbergrating = uilabel(app.Panel_graiting);
            app.notelengthnumbergrating.Visible = 'off';
            app.notelengthnumbergrating.Text = 'the values should conatin only numbers';
            app.GDD0forLabel = uilabel(app.Panel_graiting);
            app.GDD0forLabel.Text = 'GDD = 0 for: ';
            app.DistanceLCheckBox = uicheckbox(app.Panel_graiting);
            app.DistanceLCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistanceLCheckBoxValueChanged, true);
            app.DistanceLCheckBox.Text = 'Distance L';
            app.angleofincidenceCheckBox = uicheckbox(app.Panel_graiting);
            app.angleofincidenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @angleofincidenceCheckBoxValueChanged, true);
            app.angleofincidenceCheckBox.Text = 'angle of incidence ';
            app.GratingdensityCheckBox = uicheckbox(app.Panel_graiting);
            app.GratingdensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @GratingdensityCheckBoxValueChanged, true);
            app.GratingdensityCheckBox.Text = 'Grating density';
            app.notelengthnumbergrating_2 = uilabel(app.Panel_graiting);
            app.notelengthnumbergrating_2.Visible = 'off';
            app.notelengthnumbergrating_2.Text = 'No value exists for reset GDD';
            app.DropDownControlComponent = uidropdown(app.DispersionControlTab);
            app.DropDownControlComponent.Items = {'GDD', 'TOD'};
            app.DropDownControlComponent.ValueChangedFcn = createCallbackFcn(app, @DropDownControlComponentValueChanged, true);
            app.DropDownControlComponent.Value = 'GDD';
            app.asfunctionofSwitch_7Label = uilabel(app.DispersionControlTab);
            app.asfunctionofSwitch_7Label.Text = 'as function of:';
            app.asfunctionofSwitch_7 = uiswitch(app.DispersionControlTab, 'toggle');
            app.asfunctionofSwitch_7.Items = {'ω', 'λ'};
            app.asfunctionofSwitch_7.ValueChangedFcn = createCallbackFcn(app, @asfunctionofSwitch_7ValueChanged, true);
            app.asfunctionofSwitch_7.Value = 'ω';
            app.PODTextAreaTotal = uitextarea(app.DispersionControlTab);
            app.DispersionParameterorderSpinner_2Label = uilabel(app.DispersionControlTab);
            app.DispersionParameterorderSpinner_2Label.Text = 'Dispersion Parameter order';
            app.DispersionParameterorderSpinnerTotal = uispinner(app.DispersionControlTab);
            app.DispersionParameterorderSpinnerTotal.Limits = [0 10];
            app.DispersionParameterorderSpinnerTotal.RoundFractionalValues = 'on';
            app.DispersionParameterorderSpinnerTotal.ValueChangedFcn = createCallbackFcn(app, @DispersionParameterorderSpinnerTotalValueChanged, true);
            app.DispersionParameterorderSpinnerTotal.Value = 2;
            app.orderSpinner_3Label = uilabel(app.DispersionControlTab);
            app.orderSpinner_3Label.Text = 'order';
            app.orderSpinner_3 = uispinner(app.DispersionControlTab);
            app.orderSpinner_3.Limits = [2 6];
            app.orderSpinner_3.ValueChangedFcn = createCallbackFcn(app, @orderSpinner_3ValueChanged, true);
            app.orderSpinner_3.Value = 3;
            app.WavelengthrangenmSliderLabel_4 = uilabel(app.DispersionControlTab);
            app.WavelengthrangenmSliderLabel_4.Text = 'Wavelength range(nm):';
            app.WavelengthrangenmSliderControl = uislider(app.DispersionControlTab, 'range');
            app.WavelengthrangenmSliderControl.Limits = [0 2000];
            app.WavelengthrangenmSliderControl.ValueChangedFcn = createCallbackFcn(app, @WavelengthrangenmSliderControlValueChanged, true);
            app.WavelengthrangenmSliderControl.Value = [400 1600];
            app.savegraphButton_5 = uibutton(app.DispersionControlTab, 'push');
            app.savegraphButton_5.ButtonPushedFcn = createCallbackFcn(app, @savegraphButton_5Pushed, true);
            app.savegraphButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'image-.png');
            app.savegraphButton_5.Text = 'save graph ';
            app.resetgraphButton_5 = uibutton(app.DispersionControlTab, 'push');
            app.resetgraphButton_5.ButtonPushedFcn = createCallbackFcn(app, @resetgraphButton_5Pushed, true);
            app.resetgraphButton_5.Icon = fullfile(pathToMLAPP, 'icons', 'undo (1).png');
            app.resetgraphButton_5.Text = 'reset graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dispersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end